classdef Interfaz_EMG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        BCIEMGEEGUVGLabel              matlab.ui.control.Label
        Image4                         matlab.ui.control.Image
        Button_3                       matlab.ui.control.Button
        TabGroup2                      matlab.ui.container.TabGroup
        SealesenTiempoRealBitalinoTab  matlab.ui.container.Tab
        SealesentiempoRealPanel        matlab.ui.container.Panel
        SinActividadLabel              matlab.ui.control.Label
        ClaseLabel                     matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        EnpausaLabel                   matlab.ui.control.Label
        Lamp_3                         matlab.ui.control.Lamp
        MediaCuadraticaEditField       matlab.ui.control.NumericEditField
        MediaCuadraticaEditFieldLabel  matlab.ui.control.Label
        WilsonAmplitudEditField        matlab.ui.control.NumericEditField
        WilsonAmplitudEditFieldLabel   matlab.ui.control.Label
        VarianzaEditField              matlab.ui.control.NumericEditField
        VarianzaEditFieldLabel         matlab.ui.control.Label
        AverageEnergyEditField         matlab.ui.control.NumericEditField
        AverageEnergyEditFieldLabel    matlab.ui.control.Label
        KurtossisEditField             matlab.ui.control.NumericEditField
        KurtossisEditFieldLabel        matlab.ui.control.Label
        MAVEditField                   matlab.ui.control.NumericEditField
        MAVEditFieldLabel              matlab.ui.control.Label
        ZeroCrossingEditField          matlab.ui.control.NumericEditField
        ZeroCrossingEditFieldLabel     matlab.ui.control.Label
        Canal1Label                    matlab.ui.control.Label
        figFpz_2                       matlab.ui.control.UIAxes
        RobotBiopacTab                 matlab.ui.container.Tab
        TabGroup3                      matlab.ui.container.TabGroup
        MuestreoSealTab                matlab.ui.container.Tab
        UIAxes2                        matlab.ui.control.UIAxes
        HistoricoTab                   matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        ParmetrosSimulacinPanel        matlab.ui.container.Panel
        ConfiguracionesButtonGroup     matlab.ui.container.ButtonGroup
        BrazoExtendidoContraidoButton  matlab.ui.control.RadioButton
        BrazoArribaAbajoButton         matlab.ui.control.RadioButton
        MetodoDropDown                 matlab.ui.control.DropDown
        MetodoDropDownLabel            matlab.ui.control.Label
        TiempoRealPanel                matlab.ui.container.Panel
        TmuestraEditField              matlab.ui.control.NumericEditField
        TmuestraLabel                  matlab.ui.control.Label
        DesconectarButton              matlab.ui.control.Button
        CargarEntrenamientoButton      matlab.ui.control.Button
        ConectarButton                 matlab.ui.control.Button
        PuertoCOMDropDown              matlab.ui.control.DropDown
        PuertoCOMDropDownLabel         matlab.ui.control.Label
        BaudRateEditField              matlab.ui.control.NumericEditField
        BaudRateEditFieldLabel         matlab.ui.control.Label
        ControlOfflinePanel            matlab.ui.container.Panel
        SimularButton                  matlab.ui.control.Button
        SealNuevaClasificadorButton    matlab.ui.control.Button
        Image2                         matlab.ui.control.Image
        ClaseEditField                 matlab.ui.control.EditField
        ClaseEditFieldLabel            matlab.ui.control.Label
        SIMULADORBCI2023Label          matlab.ui.control.Label
        GestoActividadLabel            matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        BitalinoBluetoothTab           matlab.ui.container.Tab
        PaneldeControl                 matlab.ui.container.Panel
        Button_2                       matlab.ui.control.Button
        Button                         matlab.ui.control.Button
        EscogerEntrenamientoButton     matlab.ui.control.Button
        PararButton                    matlab.ui.control.Button
        NombreBluetoothBitalinoEditField  matlab.ui.control.EditField
        NombreBluetoothBitalinoEditFieldLabel  matlab.ui.control.Label
        EmpezarStreamingSwitch         matlab.ui.control.Switch
        EmpezarStreamingSwitchLabel    matlab.ui.control.Label
        ConectarBitalinoSwitch         matlab.ui.control.Switch
        ConectarBitalinoSwitchLabel    matlab.ui.control.Label
        Lamp_2                         matlab.ui.control.Lamp
        Lamp                           matlab.ui.control.Lamp
        ThSpinner                      matlab.ui.control.Spinner
        ThSpinnerLabel                 matlab.ui.control.Label
        TiempodemuestraEditField       matlab.ui.control.NumericEditField
        TiempodemuestraEditFieldLabel  matlab.ui.control.Label
        IniciarButton                  matlab.ui.control.Button
        ButtonsPanel                   matlab.ui.container.Panel
        LampRed                        matlab.ui.control.Lamp
        LampGreen                      matlab.ui.control.Lamp
        STOPButton                     matlab.ui.control.Button
        STARTButton                    matlab.ui.control.Button
        ParametersPanel                matlab.ui.container.Panel
        COMDropDown                    matlab.ui.control.DropDown
        COMDropDownLabel               matlab.ui.control.Label
        SPEEDEditField                 matlab.ui.control.NumericEditField
        SPEEDEditFieldLabel            matlab.ui.control.Label
        BiopacTab                      matlab.ui.container.Tab
        LearningRateEditField          matlab.ui.control.NumericEditField
        LearningRateEditFieldLabel     matlab.ui.control.Label
        EpochsEditField                matlab.ui.control.NumericEditField
        EpochsEditFieldLabel           matlab.ui.control.Label
        GuardarButton                  matlab.ui.control.Button
        ExactituddelclasificadorLabel  matlab.ui.control.Label
        EntrenarButton                 matlab.ui.control.Button
        CargarmatButton                matlab.ui.control.Button
        KernelaemplearDropDown         matlab.ui.control.DropDown
        KernelDropDownLabel            matlab.ui.control.Label
        NmerodeParticionesKSpinner     matlab.ui.control.Spinner
        KfoldsLabel                    matlab.ui.control.Label
        ValidacinCruzadaLabel          matlab.ui.control.Label
        MaquinaSoporteVectorialSVMLabel  matlab.ui.control.Label
        CapasOcultasDropDown           matlab.ui.control.DropDown
        CapasOcultasDropDownLabel      matlab.ui.control.Label
        AlgoritmoparaelentrenamientoDropDown  matlab.ui.control.DropDown
        AlgoritmoRNADropDownLabel      matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        PruebaEditFieldLabel           matlab.ui.control.Label
        SegmentosPrueba                matlab.ui.control.NumericEditField
        ValidacionEditFieldLabel       matlab.ui.control.Label
        SegmentosValidacion            matlab.ui.control.NumericEditField
        SegmentosEntrenamiento         matlab.ui.control.NumericEditField
        EntrenamientoEditFieldLabel    matlab.ui.control.Label
        ClasificadorRedNeuronalLabel   matlab.ui.control.Label
        SeleccionarclasificadorButtonGroup  matlab.ui.container.ButtonGroup
        SVMButton                      matlab.ui.control.ToggleButton
        RedNeuronalButton              matlab.ui.control.ToggleButton
        VectorCaractersticasEMGEEGLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        R17 = 0;
        COM = 0;
        Pausa = 0;
        loadedData = 0;
        SPEED = 0;
        flag_recibir = 0;
        Fs = 44100;
        s;
        Onda_Low = 7;
        Onda_High = 14;
        typeofdatastore;
        y1 = audioread('Click.wav');
        Bit=0;
        F_pb = filtro_pasa_banda(1000,20,450);    %Diseñar filtro pasa banda
        F_notch = filtro_rechaza_banda(1000);     %Diseñar filtro rechaza banda
        data_n=0;
        BanderaParaEmpezar=0;
        Estructura_Caracteristicas = 0;
        NombreBit=0;
        flag_recibir2 =0;
        T = table()
        Array_giro =[45,90,135,180,135,90,45,0,-45,-90,-135,-180,-135,-90,-45,0];
        Metros_CAREER = [0.2,0.4,0.6,0.4,0.2,0,-0.2,-0.4,-0.2,0]
        flag_recibir1=0;
        A=0;
        AlgorimoAprendizajeA;
        flag_terminar=0;
        NumberOfEpochs = 0;
        Fs_gesto_nuevo
        datosgesto_nuevo
        vector_config
        clase_prueba
        qtest
        loadedNet
        continueLoop
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.LampGreen.Enable = 'off';
            app.LampRed.Enable = 'off';
            app.Lamp_2.Enable = 'off';
            app.Lamp_3.Enable = 'off';
            app.Lamp.Enable='off';
            app.STOPButton.Enable = 'off';
            app.T.HjorthActivity = zeros(0);
            app.T.NormalizedFirstDifference = zeros(0);
            app.T.HjorthComplexity = zeros(0);
            app.T.Kurtosis = zeros(0);
            app.T.MeanEnergy = zeros(0);
            app.T.Skewness = zeros(0);
            app.T.ShannonEntropy = zeros(0);
            app.T.HjorthMobility = zeros(0);
            app.T.PCALatent = zeros(0);
            app.T.PCAMu = zeros(0);
            app.T.Clase = zeros(0);
        end

        % Callback function
        function RegresarButtonPushed(app, event)
            Interfaz_Inicio
            delete(app)
        end

        % Callback function
        function ActivarStreamingSwitchValueChanged(app, event)

        end

        % Callback function
        function ButtonPushed(app, event)
            global R17
            q1 = [0; 0;90; 0; -45; 0];
            R17fKine(R17, q1);
        end

        % Callback function
        function RegresarButtonPushed2(app, event)
            clear all
            Interfaz_Inicio
            delete(app)
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            soundsc(app.y1,app.Fs);
            app.STARTButton.Enable = 'off';
            app.STOPButton.Enable ='on';
            app.LampGreen.Enable = 'on';
            app.LampRed.Enable = 'off';
            app.EnpausaLabel.Enable='off';
            app.SPEED = app.SPEEDEditField.Value;
            %app.R17 = R17Init(app.COM, app.SPEED);
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.LampGreen.Enable = 'off';
            app.LampRed.Enable = 'on';
            app.STOPButton.Enable = 'off';
            app.STARTButton.Enable = 'on';           
            R17Close(app.R17);
        end

        % Value changed function: COMDropDown
        function COMDropDownValueChanged(app, event)
 
            app.COM = app.COMDropDown.Value;
            % Para visualizar en el workspace
            assignin('base', 'com', app.COM);
        end

        % Callback function
        function Movement1ButtonPushed(app, event)
            
            q2 = [0; 0;deg2rad(90); 0; deg2rad(-45); 0];
            R17fKine(app.R17, q2);
        end

        % Callback function
        function Movement2ButtonPushed(app, event)
            
            q1 = [0; deg2rad(90);0; deg2rad(45); 0; 0];
            R17fKine(app.R17, q1);
        end

        % Button pushed function: IniciarButton
        function IniciarButtonPushed(app, event)

            %Extracción características
            app.Pausa=0;
            ContGiro = 0;
            giro = 0;           
            ContMetros = 0;
            Metros = 0;           
            time = 0;
            Th  = app.ThSpinner.Value;
            %ga = 0.4;
            b_act = 0;            
            Tiempo_DMuestra = app.TiempodemuestraEditField.Value;           
            app.flag_terminar=0;
            % Pause to acquire data for 20 seconds
            %pause(10);
            % Read the data from the device      
            app.Lamp.Enable = 'on';           
            while (app.flag_recibir2 ==1) && (app.flag_recibir1==1)&& (app.flag_terminar==0)
                while (time < Tiempo_DMuestra)      %la variable m determina el tiempoque nosotros asignaramos para llenar el buffer
                    pause(1)   % se le da una pausa de 1 segundo por cada vez que pase, para que se logre llenar el buffer en el tiempo que se requiere
                    time = time + 1 ;%variable que aumenta cada 1 segundo.
                    app.Lamp_3.Enable = 'on';
                end
                
                data = read(app.Bit);% tomamos los ultimos valores en el buffer.
                app.Lamp_3.Enable = 'off';
                %n=n+1%contador para ingresar los datos a la tabla.
                time = 0; %se reinicia el contador para la siguiente vez que se llene el buffer
                EMG_raw = data(:,6); %se toma solo la columna de la senal EMG
                EMG = EMG_raw*(3.3/1024);%se convierte la senal a la escala de 3.3 V
                data_f = filter(app.F_pb, EMG');                          %Aplicar filtro pasa bandas
                app.data_n = filter(app.F_notch, data_f);                       %Aplicar filtro notch               
                wt = cwt(app.data_n(300:end));              
                Actividad = max(max(abs(wt)));                               
                app.ZeroCrossingEditField.Value = jZeroCrossing(app.data_n,0.34);
                app.MAVEditField.Value = jMeanAbsoluteDeviation(app.data_n);
                app.KurtossisEditField.Value = jKurtosis(app.data_n);
                app.AverageEnergyEditField.Value = jAverageEnergy(app.data_n);
                app.VarianzaEditField.Value = jVariance(app.data_n);
                app.WilsonAmplitudEditField.Value = jWillisonAmplitude(app.data_n,0.01);
                app.MediaCuadraticaEditField.Value = jRootMeanSquare(app.data_n);               
                b_act = 0 ;
                app.T.ZC(1) = jZeroCrossing(app.data_n,0.1);
                app.T.MAV(1) = jMeanAbsoluteDeviation(app.data_n);
                app.T.Kurtosis(1) = jKurtosis(app.data_n);
                app.T.AverageE(1) = jAverageEnergy(app.data_n);
                app.T.Varianza(1) = jVariance(app.data_n);
                app.T.AmplitudWilson(1) = jWillisonAmplitude(app.data_n,0.1);
                app.T.MediaCuadratica(1) = jRootMeanSquare(app.data_n);
                
                if  Actividad > Th  &&  b_act == 0
                    %Activar bandera de inicio de actividad
                    b_act = 1;
                    app.SinActividadLabel.Text = "Se Detecto Actividad"
                end
                
                if (b_act ==1) && (app.Pausa==0)                 
                    app.EnpausaLabel.Enable='off';
                    yfit = app.A.predictFcn(app.T);
                    switch yfit
                        case 'WristUp'
                            %                             ContGiro = ContGiro + 1;
                            %                             if ContGiro == 17
                            %                                 ContGiro =1 ;
                            %                             end
                            %                             giro = app.Array_giro(ContGiro) ;
                            %                             q2 = [Metros; deg2rad(giro);0; 0;0; 0];
                            %                             R17fKine(app.R17, q2);
                            
                            app.ClaseLabel.Text = "WristUp"
                            app.Image.ImageSource = imread("WristUp.jpg");
                        case 'HandUp'
                            %                             ContMetros = ContMetros +1;
                            %                             if ContMetros == 12
                            %                                 ContMetros =1;
                            %                             end
                            %                             Metros = app.Metros_CAREER(ContMetros);
                            %                             q2 = [Metros; deg2rad(giro);0; 0; 0; 0];
                            %                             R17fKine(app.R17, q2);
                            app.ClaseLabel.Text = "HandUp"
                            app.Image.ImageSource = imread("manita.jfif");
                    end
                    ylim(app.figFpz_2, [min(app.data_n) max(app.data_n)]);
                    Epocas = size(app.data_n,2) ; %se toma el tamano del buffer obtenido
                    L = Epocas; %longitud del vector epocas
                    
                    t=linspace(0, Epocas/1000, L);
                    h1 = plot(app.figFpz_2, t, app.data_n) %se plotea esa muestra
                    app.SinActividadLabel.Text = "Sin Actividad"
                end
                
                if app.Pausa ==1;
                    app.EnpausaLabel.Enable='on';
                    
                end
            end
            
        end

        % Callback function
        function ConectarBitalinoSwitchValueChanged(app, event)


        end

        % Value changed function: ConectarBitalinoSwitch
        function ConectarBitalinoSwitchValueChanged2(app, event)
            
            value = app.ConectarBitalinoSwitch.Value;
            
            if strcmp( value,'On')
                app.NombreBit = app.NombreBluetoothBitalinoEditField.Value
                app.Bit = Bitalino(char(app.NombreBit)); %Creamos la conexion con el dispositivo bitalino y esa conexion la volvemos un objeto.
                app.flag_recibir1=1;
                app.Lamp.Enable="on"
            else
                app.Lamp.Enable="off"
                app.flag_recibir1=0;
                
                
                clear all


            end
        end

        % Value changed function: EmpezarStreamingSwitch
        function EmpezarStreamingSwitchValueChanged(app, event)
            value = app.EmpezarStreamingSwitch.Value;
            
            if strcmp( value,'On')
                
                app.flag_recibir2 =1;
                startBackground(app.Bit)
                app.Lamp_2.Enable="on";
                
                
            else
                app.flag_recibir2 =0;
                stopBackground(app.Bit)
                app.Lamp_2.Enable="off";
            end


        end

        % Button pushed function: EscogerEntrenamientoButton
        function EscogerEntrenamientoButtonPushed(app, event)
            [file,folder]=uigetfile('*.mat');
            if isequal(file, 0)
                disp('No se ha seleccionado ningún archivo .mat'); % Display a message or handle the case
                return;
            end
            filename=fullfile(folder,file);
            s=load(filename);
            C= struct2cell(s);
            app.A= cell2mat(C);
        end

        % Button pushed function: PararButton
        function PararButtonPushed(app, event)
            app.flag_terminar=1;           
            app.BanderaParaEmpezar=0;
            app.T = table();
            app.data_n=0;
            if app.Pausa == 1
                app.LarecoleccinestenPausaLabel.Visible = 'off';
                app.Pausa=0;
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.Pausa=0; 
        end

        % Button pushed function: Button
        function ButtonPushed2(app, event)
            app.Pausa=1;
        end

        % Value changed function: SPEEDEditField
        function SPEEDEditFieldValueChanged(app, event)
            value = app.SPEEDEditField.Value;
            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab; % Get the selected tab
            if selectedTab == app.BiopacTab
                app.PaneldeControl.Visible = 'off';
                drawnow;
            else
                app.PaneldeControl.Visible = 'on';
                drawnow;
            end
            
        end

        % Callback function
        function Button_4Pushed(app, event)

        end

        % Callback function
        function IniciarButton_2Pushed(app, event)
            app.Lamp_3.Enable = 'on'; % INICIAR ENTRENAMIENTO LAMP
            app.EnpausaLabel.Enable='off';
            
            % Initializing Variables
            app.Pausa=0;
            ContGiro = 0;
            giro = 0;           
            ContMetros = 0;
            Metros = 0;           
            time = 0;
            Th  = 0.2; %app.ThSpinner.Value; %NO SE QUE ES ThSpinner en DATA
            b_act = 0;            
            %Tiempo_DMuestra = app.TiempodemuestraEditField.Value; % DEBO EDITAR DE DONDE SE TOMA
            
            app.flag_terminar=0;
            data = app.A; % Access the 'data' field of the struct
            EMG_raw = data(:,1); %se toma solo la columna de la senal EMG
            
            EMG = EMG_raw*(3.3/1024);%se convierte la senal a la escala de 3.3 V
            data_f = filter(app.F_pb, EMG');                          %Aplicar filtro pasa bandas
            app.data_n = filter(app.F_notch, data_f);                       %Aplicar filtro notch               
            wt = cwt(app.data_n(300:end));              
            Actividad = max(max(abs(wt)));                               
            app.ZeroCrossingEditField.Value = jZeroCrossing(app.data_n,0.34);
            app.MAVEditField.Value = jMeanAbsoluteDeviation(app.data_n);
            app.KurtossisEditField.Value = jKurtosis(app.data_n);
            app.AverageEnergyEditField.Value = jAverageEnergy(app.data_n);
            app.VarianzaEditField.Value = jVariance(app.data_n);
            app.WilsonAmplitudEditField.Value = jWillisonAmplitude(app.data_n,0.01);
            app.MediaCuadraticaEditField.Value = jRootMeanSquare(app.data_n);               
            b_act = 0 ;
            app.T.ZC(1) = jZeroCrossing(app.data_n,0.1);
            app.T.MAV(1) = jMeanAbsoluteDeviation(app.data_n);
            app.T.Kurtosis(1) = jKurtosis(app.data_n);
            app.T.AverageE(1) = jAverageEnergy(app.data_n);
            app.T.Varianza(1) = jVariance(app.data_n);
            app.T.AmplitudWilson(1) = jWillisonAmplitude(app.data_n,0.1);
            app.T.MediaCuadratica(1) = jRootMeanSquare(app.data_n);
            
            if  Actividad > Th  &&  b_act == 0
                %Activar bandera de inicio de actividad
                b_act = 1;
                app.SinActividadLabel.Text = "Se Detecto Actividad"
            end         
            if (b_act ==1) && (app.Pausa==0)                 
                app.EnpausaLabel.Enable='off';
                yfit = app.A.predictFcn(app.T);
                switch yfit
                    case 'WristUp'
                        %                             ContGiro = ContGiro + 1;
                        %                             if ContGiro == 17
                        %                                 ContGiro =1 ;
                        %                             end
                        %                             giro = app.Array_giro(ContGiro) ;
                        %                             q2 = [Metros; deg2rad(giro);0; 0;0; 0];
                        %                             R17fKine(app.R17, q2);
                        
                        app.ClaseLabel.Text = "WristUp"
                        app.Image.ImageSource = imread("WristUp.jpg");
                    case 'HandUp'
                        %                             ContMetros = ContMetros +1;
                        %                             if ContMetros == 12
                        %                                 ContMetros =1;
                        %                             end
                        %                             Metros = app.Metros_CAREER(ContMetros);
                        %                             q2 = [Metros; deg2rad(giro);0; 0; 0; 0];
                        %                             R17fKine(app.R17, q2);
                        app.ClaseLabel.Text = "HandUp"
                        app.Image.ImageSource = imread("manita.jfif");
                end
                ylim(app.figFpz_2, [min(app.data_n) max(app.data_n)]);
                Epocas = size(app.data_n,2) ; % se toma el tamano de data obtenido
                L = Epocas; % longitud del vector epocas
                t=linspace(0, Epocas/1000, L);
                h1 = plot(app.figFpz_2, t, app.data_n) % se plotea esa muestra
                app.SinActividadLabel.Text = "Sin Actividad"
            end
            
            if app.Pausa ==1;
                app.EnpausaLabel.Enable='on';
                
            end
            
        end

        % Callback function
        function OpenFileButtonPushed(app, event)
             % Open a file dialog and allow user to select a .mat file
        [filename, pathname] = uigetfile('*.mat', 'Select a .mat file');        
        % Check if the user clicked Cancel
        if isequal(filename, 0)
            disp('No se ha seleccionado ningún archivo .mat'); % Display a message or handle the case
            return;
        end
        
        % Load the data from the selected .mat file
        filePath = fullfile(pathname, filename);
        s = load(filePath);
        % Check if the loaded data is a cell array
        if iscell(s)
            disp('Cargando...');
            app.loadedData= struct2cell(s);
            app.A= cell2mat(app.loadedData);
            % app.SPEED = app.SPEEDEditField.Value aun no se lo que hace
        else
            disp('El .mat no contiene ningún cell array');
            % Handle different data types
        end
        end

        % Callback function
        function COMDropDown_2ValueChanged(app, event)
            value = app.PuertoCOMDropDown.Value;
            % Para visualizar en el workspace
            assignin('base', 'com', app.COM);
            
        end

        % Button pushed function: CargarmatButton
        function CargarmatButtonPushed(app, event)
            %Seleccionar archivo MAT con vector de caracteristicas y
            %etiquetas
            
            
            [file,path,~] = uigetfile({'*.mat'}, 'Seleccionar Variable Matlab');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path,file)]);           
                espacio_trabajo_temp = load(strcat(path,file),'Features_result');
                app.Estructura_Caracteristicas = espacio_trabajo_temp.Features_result;
                app.vector_config = espacio_trabajo_temp.Features_result.Vector_Caracteristicas;
            end

        end

        % Value changed function: SegmentosEntrenamiento
        function SegmentosEntrenamientoValueChanged(app, event)
            divitrain = app.SegmentosEntrenamiento.Value;
            divitest = app.SegmentosPrueba.Value;
            divival = app.SegmentosValidacion.Value;
            if divitrain+divitest+divival ~=100
               %Mensaje de advertencia 
                message = sprintf('Los porcentajes deben sumar 100%');
                uialert(app.UIFigure, message, 'Advertencia', 'Icon', 'warning');                   
            end            
            
        end

        % Value changed function: SegmentosValidacion
        function SegmentosValidacionValueChanged(app, event)
           divitrain = app.SegmentosEntrenamiento.Value;
            divitest = app.SegmentosPrueba.Value;
            divival = app.SegmentosValidacion.Value;
            if divitrain+divitest+divival ~=100
               %Mensaje de advertencia 
                message = sprintf('Los porcentajes deben sumar 100%');   
                uialert(app.UIFigure, message, 'Advertencia', 'Icon', 'warning');
            end  
            
        end

        % Value changed function: SegmentosPrueba
        function SegmentosPruebaValueChanged(app, event)
            divitrain = app.SegmentosEntrenamiento.Value;
            divitest = app.SegmentosPrueba.Value;
            divival = app.SegmentosValidacion.Value;
            if divitrain+divitest+divival ~=100
               %Mensaje de advertencia 
                message = sprintf('Los porcentajes deben sumar 100%');    
                uialert(app.UIFigure, message, 'Advertencia', 'Icon', 'warning');
            end  
            
        end

        % Selection changed function: 
        % SeleccionarclasificadorButtonGroup
        function SeleccionarclasificadorButtonGroupSelectionChanged(app, event)
            if (app.RedNeuronalButton.Value == 1)
                app.SegmentosEntrenamiento.Enable = 'on';
                app.SegmentosValidacion.Enable = 'on';
                app.SegmentosPrueba.Enable = 'on';
                app.CapasOcultasDropDown.Enable = 'on';
                app.AlgoritmoparaelentrenamientoDropDown.Enable = 'on';
                app.LearningRateEditField.Enable ='on';
                app.EpochsEditField.Enable = 'on';
                app.NmerodeParticionesKSpinner.Enable = 'off';
                app.KernelaemplearDropDown.Enable = 'off';

            else
                app.SegmentosEntrenamiento.Enable = 'off';
                app.LearningRateEditField.Enable ='off';
                app.EpochsEditField.Enable = 'off';
                app.SegmentosValidacion.Enable = 'off';
                app.SegmentosPrueba.Enable = 'off';
                app.CapasOcultasDropDown.Enable = 'off';
                app.AlgoritmoparaelentrenamientoDropDown.Enable = 'off';

                app.NmerodeParticionesKSpinner.Enable = 'on';
                app.KernelaemplearDropDown.Enable = 'on';
            end
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            Clasificador_Entrenado = struct();
            Tipo_class = app.Estructura_Caracteristicas.Tipo_Clasificacion;
            Tipo_car = app.Estructura_Caracteristicas.Tipo_Caracteristicas;
            
            %Se lee el vector de opciones, primero se descompone el texto            
            Cellop = strsplit(app.Estructura_Caracteristicas.(['Car_', app.typeofdatastore]).Caracteristicas,',');
            Vop = app.Estructura_Caracteristicas.Vector_Caracteristicas;
            
            % MEJOR NO TOCAR
            if Tipo_car == 1
                %CarWavelet
                Wmadre = app.Estructura_Caracteristicas.OndaMadre;
                Wn = app.Estructura_Caracteristicas.NivelDescomposicion;
            end                        
            
            Clasificador_Entrenado.Clasificador = app.AlgorimoAprendizajeA;
            if(app.RedNeuronalButton.Value ==1)                
                Clasificador_Entrenado.Tipo_Clasificador = 0;                
            else
                Clasificador_Entrenado.Tipo_Clasificador = 1;                
            end
            Clasificador_Entrenado.Caracteristicas.Tipo_Caracteristicas = Tipo_car;
            if (Tipo_car == 1)
                Clasificador_Entrenado.Caracteristicas.Madre = Wmadre;
                Clasificador_Entrenado.Caracteristicas.Descomp = Wn;
            end            
            Clasificador_Entrenado.Vector_op = Vop;
            Clasificador_Entrenado.No_Clases = Tipo_class;  
            
            %Guardar archivo MAT
            startingFolder = 'C:\Program Files\MATLAB'
            if ~exist(startingFolder, 'dir')
                startingFolder = pwd;
            end
            % Put in the name of the mat file that the user wants to save.
            % defaultFileName = fullfile(startingFolder, '*.mat')
            [file,path] = uiputfile('Clasificador.mat','Save file name');
            if file == 0
            % User clicked the Cancel button.
             return;
            end
            
            %AQUI ESTABA LO DEL STRUCT DE FEATURES ANTES
            
            %Guardar archivo en la ruta seleccionada
            fullFileName = fullfile(path, file);            
            save(fullFileName,'Clasificador_Entrenado')
            
            %Mensaje de exito 
            message = sprintf(['Archivo ',file,' guardado exitosamente']);
            uialert(app.UIFigure, message, 'Éxito', 'Icon', 'success');

        end

        % Button pushed function: EntrenarButton
        function EntrenarButtonPushed(app, event)
            %Se verifica tipo de análisis
            app.typeofdatastore = 'gesto_1';

            %empleado para extraer las características
            Tipo_class = app.Estructura_Caracteristicas.Tipo_Clasificacion;
            Tipo_car = app.Estructura_Caracteristicas.Tipo_Caracteristicas;
            
                        
            %Se cargan los vectores de caracteristicas correspondientes
            caracteristicas_gesto_1 = app.Estructura_Caracteristicas.Car_gesto_1.VectorCaracteristicas_gesto_1;            
            caracteristicas_gesto_2 =  app.Estructura_Caracteristicas.Car_gesto_2.VectorCaracteristicas_gesto_2;
            
            %Homogeneización de las señales: Ya que puede que las señales
            %tengan distinta duración, puede que tengan una cantidad de
            %ventanas distintas entre ellas. Se acotará a que todas tengan
            %la misma cantidad que la que tenga menor número de ventanas
            
            cantidad_ventanas = [length(caracteristicas_gesto_1),length(caracteristicas_gesto_2)];


            %Se encuentra cuál es la menor cantidad de ventanas entre los
            %vectores involucrados en el análisis, este es el máximo de
            %ventanas que pueden haber. Se acotan los vectores de car.
            cantidad_ventanasmaximas = min(cantidad_ventanas);
            
            if (Tipo_class ==2)
                caracteristicas_gesto_1 = caracteristicas_gesto_1(1:cantidad_ventanasmaximas,:);
                caracteristicas_gesto_2 = caracteristicas_gesto_2(1:cantidad_ventanasmaximas,:);
                %Unimos los vectores en una matriz con todas las
                %características
                matriz_completa_caracteristicas =[caracteristicas_gesto_1',caracteristicas_gesto_2'];


            %Se crea y entrena el clasificador especificado
            if (app.RedNeuronalButton.Value == 1)                
                %Se emplea encoding One-Hot Para las etiquetas de la RN
                if (Tipo_class == 2)
                    vector_etiquetas_emg =[ones(1,cantidad_ventanasmaximas),zeros(1,cantidad_ventanasmaximas);...
                                           zeros(1,cantidad_ventanasmaximas),ones(1,cantidad_ventanasmaximas)];
                end


                %Se establecen los parámetros y se crea la red neuronal
                Func_entrenamiento = app.AlgoritmoparaelentrenamientoDropDown.Value;
                CapasOcultas = str2double(app.CapasOcultasDropDown.Value);
                
                
                net = patternnet(CapasOcultas, Func_entrenamiento);
                % Set the number of epochs based on the stored value
                net.trainParam.epochs = app.EpochsEditField.Value;
                net.trainParam.lr = app.LearningRateEditField.Value;
                %Se entrena la red neuronal
                
                % COMPARACION
                % x = matriz_completa_caracteristicas
                % t = vector_etiquetas_emg
                % y = etiquetas_salida


                [net,tr] = train(net,matriz_completa_caracteristicas,vector_etiquetas_emg);
                divitrain = app.SegmentosEntrenamiento.Value;
                divitest = app.SegmentosPrueba.Value;
                divival = app.SegmentosValidacion.Value;


                %Se revisa que el % con los tres grupos sea de 100%
                if divitrain+divitest+divival ~=100
                    divitrain= 70;
                    divitest = 20;
                    divival = 10;                    
                end
                
                net.divideParam.trainRatio = divitrain/100;
                net.divideParam.valRatio = divival/100;
                net.divideParam.testRatio = divitest/100;
                % Testear la red
                % y = net(x);
                
                etiquetas_salida = net(matriz_completa_caracteristicas); 
                
                app.AlgorimoAprendizajeA = net;
                [~,cm] = confusion(vector_etiquetas_emg,etiquetas_salida);
                
                if (Tipo_class == 2)
                    gesto_1_11 = cm(1,1);%gesto_1 correcto
                    gesto_1_12 = cm(1,2);%gesto_1 falso (sano)                   
                    
                    gesto_2_21 = cm(2,1);%gesto_2 falso (ictal)
                    gesto_2_22 = cm(2,2);%gesto_2 correcto
                    
                    accu = num2str(100*((gesto_1_11+gesto_2_22) / (gesto_1_11+gesto_1_12+...
                                        gesto_2_21+gesto_2_22)));  %Exactitud
                    figure;
                    plotconfusion(vector_etiquetas_emg,etiquetas_salida,{'Matriz de Confusión'});
                    set(gca,'xticklabel',{'gesto_1' 'gesto_2' 'Exactitud'})
                    set(gca,'yticklabel',{'gesto_1' 'gesto_2' ''})                                        
                    app.ExactituddelclasificadorLabel.Text = append("Exactitud Clasificador: ",num2str(accu),"%");
                    figure;
                    plotperform(tr);     

                end


            else (app.SVMButton.Value == 1) %SVM
                if (Tipo_class == 2)
                    %Se emplea un vector columna con los números 1 a 4 para las
                    %etiquetas de la SVM
                    vector_etiquetas_emg = [app.Estructura_Caracteristicas.Car_gesto_1.Etiquetas_gesto_1(1:cantidad_ventanasmaximas,:);...
                                            app.Estructura_Caracteristicas.Car_gesto_2.Etiquetas_gesto_2(1:cantidad_ventanasmaximas,:)];
                end
                
                
                %Se transpone la matriz de características para este
                %clasificador (SVM)
                matriz_completa_caracteristicas = matriz_completa_caracteristicas';
                
                %Validacion cruzada para el entrenamiento del clasificador
                k = app.NmerodeParticionesKSpinner.Value;
                cvFolds = crossvalind('Kfold', vector_etiquetas_emg, k);   
                cp = classperf(vector_etiquetas_emg);        %# init performance tracker
                for i = 1:k                                  %# for each fold
                    testIdx = (cvFolds == i);                %# get indices of test instances
                    trainIdx = ~testIdx;
                    %Obtener conjuntos train y test
                    datos_entrenamiento = matriz_completa_caracteristicas(trainIdx,:);
                    etiquetas_entrenamiento = vector_etiquetas_emg(trainIdx,:);
                    datos_prueba = matriz_completa_caracteristicas(testIdx,:);
                    etiquetas_prueba = vector_etiquetas_emg(testIdx,:);                  
                end
                
                %Parámetros y entrenamiento de la SVM
                kernel = app.KernelaemplearDropDown.Value;
                t_SVM = templateSVM('Standardize',true,'KernelFunction',kernel);
                model_svm = fitcecoc(datos_entrenamiento,etiquetas_entrenamiento,'Learners',t_SVM,'FitPosterior',true,...
                    'Verbose',2);
                [labelPred,~] =  predict(model_svm, datos_prueba);
                
                % COMPARACION
                % x = matriz_completa_caracteristicas
                % t = vector_etiquetas_emg
                % y = etiquetas_generadas
                
                etiquetas_generadas = labelPred;                
                Prueba_eti = categorical(etiquetas_prueba');
                Predict_eti = categorical(etiquetas_generadas');
                app.AlgorimoAprendizajeA = model_svm;
                %Graficamos la matriz de confusión
                plotconfusion(Prueba_eti,Predict_eti,{'Matriz de Confusión'});
                if (Tipo_class == 2)
                    %Se emplea un vector columna con los números 1 a 4 para las
                    %etiquetas de la SVM
                    set(gca,'xticklabel',{'gesto_1' 'gesto_2' 'Exactitud'})
                    set(gca,'yticklabel',{'gesto_1' 'gesto_2' ''})
                    %Calculamos la exactitud del clasificador
                    matriz = confusionmat(Prueba_eti,Predict_eti);
                    s = sum(matriz);
                    accu = 100*(matriz(1,1)+matriz(2,2))/(s(1)+s(2));
                    app.ExactituddelclasificadorLabel.Text = append("Exactitud del clasificador: ",num2str(accu),"%");
                end 

            end
            end
                    
        end

        % Callback function
        function EntrenarEEGButtonPushed(app, event)
   
        end

        % Callback function
        function CargarmatButton_2Pushed(app, event)

        end

        % Callback function
        function CargarEntrenamientoButtonPushed(app, event)
            % Button pushed function: CargarmatButton_4
            % Open a file dialog to select the MAT file containing the new signal
            [file, path] = uigetfile('*.mat', 'Select a MAT file');
        
            % Check if the user canceled the file selection
            if file == 0
                return;  % User clicked the Cancel button.
            end
        
            % Load the MAT file
            fullFileName = fullfile(path, file);
            loadedData = load(fullFileName);
        
            % Check if the loaded data contains Clasificador_Entrenado
            if isfield(loadedData, 'Clasificador_Entrenado')
                % The loaded data contains a Clasificador_Entrenado struct
                Clasificador_Entrenado = loadedData.Clasificador_Entrenado;
        
                % Check the type of signal (gesto_1 or gesto_2)
                if Clasificador_Entrenado.No_Clases == 1
                    % It's a gesto_1 type of signal
                    disp('Loaded signal is of type gesto_1');
                elseif Clasificador_Entrenado.No_Clases == 2
                    % It's a gesto_2 type of signal
                    disp('Loaded signal is of type gesto_2');
                else
                    % The class type is not recognized
                    disp('Loaded signal class is unknown.');
                end
            else
                % The loaded data does not contain Clasificador_Entrenado
                disp('Loaded data does not contain a Clasificador_Entrenado struct.');
            end


        end

        % Button pushed function: SealNuevaClasificadorButton
        function SealNuevaClasificadorButtonPushed(app, event)
            Features_result_nuevo = struct();
            Matriz_features_gesto_nuevo = 0;
            channel_ventana_gesto_nuevo = 0;
            %Seleccionar archivo obtenido de base de datos
            [file,path,~] = uigetfile({'*.mat'}, 'Selecciona la señal nueva');
            %Si no se hace seleccion
            if isequal(file,0)
                 disp('Usuario canceló operación');
            else
                disp(['EL usuario selecccionó ', fullfile(path,file)]);
                %Asegurarse que el workspace cargado contenga la variable con
                %el nombre 'gesto_nuevo'
                workspace = load(strcat(path,file),'gesto_sample');
                %Cargamos la señal EMG a la variable de datos respectiva
                app.datosgesto_nuevo = workspace.gesto_sample.data;
                app.Fs_gesto_nuevo = workspace.gesto_sample.sampling_frequency;
                
            end
           if app.vector_config(end) == 0 % TIEMPO CONTINUO 
               [vector_gesto_nuevo, ventanadatos_gesto_nuevo, ~] = FeaturesV2023(app.datosgesto_nuevo, 1, length(app.datosgesto_nuevo), 1, app.vector_config);
               % Hacer algo con las características extraídas de gesto_nuevo, por ejemplo, almacenarlas                
               Matriz_features_gesto_nuevo = vector_gesto_nuevo;
               channel_ventana_gesto_nuevo = ventanadatos_gesto_nuevo;
               Features_result_nuevo.Car_gesto_nuevo.VectorCaracteristicas_gesto_nuevo = vector_gesto_nuevo;                            
               Features_result_nuevo.Car_gesto_nuevo.Caracteristicas = app.vector_config;
               Features_result_nuevo.Car_gesto_nuevo.canales = 1;
               Features_result_nuevo.Car_gesto_nuevo.data = ventanadatos_gesto_nuevo;
               Features_result_nuevo.Car_gesto_nuevo.Ventana =  length(app.datosgesto_nuevo);
               Features_result_nuevo.Car_gesto_nuevo.Etiquetas_gesto_nuevo =  ones(length(Features_result_nuevo.Car_gesto_nuevo.VectorCaracteristicas_gesto_nuevo),1);
           else
               % ESTE SI REQUIERE DE LA Fs, FRECUENCIA
               [vector_gesto_nuevo, ventanadatos_gesto_nuevo, ~] = FeaturesV3023(app.datosgesto_nuevo,app.Fs_gesto_nuevo, 1, length(app.datosgesto_nuevo), 1, app.vector_config);
               % Hacer algo con las características extraídas de gesto_nuevo, por ejemplo, almacenarlas                
               Matriz_features_gesto_nuevo = vector_gesto_nuevo;
               channel_ventana_gesto_nuevo = ventanadatos_gesto_nuevo;
               Features_result_nuevo.Car_gesto_nuevo.VectorCaracteristicas_gesto_nuevo = vector_gesto_nuevo;                            
               Features_result_nuevo.Car_gesto_nuevo.Caracteristicas = app.vector_config;
               Features_result_nuevo.Car_gesto_nuevo.canales = 1;
               Features_result_nuevo.Car_gesto_nuevo.data = ventanadatos_gesto_nuevo;
               Features_result_nuevo.Car_gesto_nuevo.Ventana =  length(app.datosgesto_nuevo);
               Features_result_nuevo.Car_gesto_nuevo.Etiquetas_gesto_nuevo =  ones(length(Features_result_nuevo.Car_gesto_nuevo.VectorCaracteristicas_gesto_nuevo),1);
           end
           
           
           x_prueba = Features_result_nuevo.Car_gesto_nuevo.VectorCaracteristicas_gesto_nuevo;
           
           
            [file, path] = uigetfile('*.mat', 'Seleccionar entrenamiento');
        
            % Check if the user canceled the file selection
            if file == 0
                return;  % User clicked the Cancel button.
            end
        
            % Load the MAT file
            fullFileName = fullfile(path, file);
            loadedData = load(fullFileName);
        
            % Check if the loaded data contains Clasificador_Entrenado
            if isfield(loadedData, 'Clasificador_Entrenado')
                % The loaded data contains a Clasificador_Entrenado struct
                Clasificador_Entrenado = loadedData.Clasificador_Entrenado;
            else 
                disp('Usuario canceló operación');
            end
           net = loadedData.Clasificador_Entrenado.Clasificador;
           % Assuming your loaded network is stored in the variable 'net'
           inputSize = net.inputs{1}.size;
           numFeatures = inputSize;  % Replace with the correct value
           x_prueba = reshape(x_prueba, [numFeatures, 1]);

           y_prueba = net(x_prueba);
           app.clase_prueba = vec2ind(y_prueba);
          
           app.ClaseEditField.Value = num2str(app.clase_prueba);
         
           
          % MOSTRAR GESTO
            if app.clase_prueba == 1
                % Set image for class 1
                image1 = imread('C:\Users\fersa\Desktop\UVG\Sem2_1\Estudio-Epilepsia-2023\extract_tool\Matlab_AutoExtract\imagenes\WristUp.jpg');
                app.Image2.ImageSource = image1;
            elseif app.clase_prueba == 2
                % Set image for class 2
                image2 = imread('C:\Users\fersa\Desktop\UVG\Sem2_1\Estudio-Epilepsia-2023\extract_tool\Matlab_AutoExtract\imagenes\WristDown.jpg');
                app.Image2.ImageSource = image2;
            else
                % Handle other cases if needed
                % For example, if clase_prueba is neither 1 nor 2
                % You can set a default image or perform some other action here
            end


           %TEST FUNCTIONALITY
%            disp(clase_prueba)
        end

        % Button pushed function: SimularButton
        function SimularButtonPushed(app, event)
             class = app.clase_prueba;
             
             
            % manejo seleccion de gestos
            if app.BrazoArribaAbajoButton.Value == 1;
            
                if class ==1
                    app.qtest = [pi/2, pi/6, pi/3, -pi/2, pi/3, -pi/3]'; 
                elseif class ==2
                    app.qtest = [pi/2, pi/6, pi/3, pi/2, -pi/3, -pi/3]';
                end
             else
                if class == 1
                    app.qtest = [pi/2, 0, pi, -pi/2, pi/3, -pi/3]';
                elseif class == 2
                    app.qtest = [0.1, pi/2, 0, pi/2, -pi/3, 0]';
                end
            end
            % configuracion inicio
            q0 = zeros(6, 1);
            
            % possible fields: {'pinv', 'dampedls', 'transpose'}
            Tdtest = robot_fkine(app.qtest);
            
            % seleccion interactiva
            method_ikine = app.MetodoDropDown.Value;
            [qpos,qpos_hist] = robot_ikine(Tdtest, q0, 'pos', method_ikine);
            
            %% Simulacion
            mdl_puma560;
          
            % Plot on the axes
            plot(app.UIAxes, qpos_hist', 'LineWidth', 1);
            
            ylabel(app.UIAxes, '$\mathbf{q}_k$', 'Interpreter', 'latex', 'FontSize', 18);
            xlabel(app.UIAxes, '$k$', 'Interpreter', 'latex', 'FontSize', 18);
            grid(app.UIAxes, 'minor');
            
            % Animación del histórico
            subplot(1,1,1);
            p560.plot3d(q0');
            pause(0.5);
            p560.plot3d(qpos');
             

        end

        % Selection changed function: ConfiguracionesButtonGroup
        function ConfiguracionesButtonGroupSelectionChanged(app, event)
           
         
        end

        % Button down function: BiopacTab
        function BiopacTabButtonDown(app, event)
            % Get a handle to the target tab group
    targetTabGroup = app.TabGroup2; % Replace with the actual name
    
    % Set the SelectedTab property to the desired tab
    targetTabGroup.SelectedTab = app.RobotBiopacTab; % Replace with the actual tab name
        end

        % Button down function: BitalinoBluetoothTab
        function BitalinoBluetoothTabButtonDown(app, event)
                      % Get a handle to the target tab group
    targetTabGroup = app.TabGroup2; % Replace with the actual name
    
    % Set the SelectedTab property to the desired tab
     targetTabGroup.SelectedTab = app.SealesenTiempoRealBitalinoTab; % Replace with the actual tab name
        end

        % Button pushed function: Button_3
        function Button_3Pushed3(app, event)
            Interfaz_Inicio
            delete(app)
        end

        % Button pushed function: ConectarButton
        function ConectarButtonPushed(app, event)
           % REAL TIME PLOT
           
           app.continueLoop = true;
           while app.continueLoop
            ax = app.UIAxes2;
            hLine = animatedline(ax, 'Color', 'b', 'LineWidth', 1, 'MaximumNumPoints', 100); % Adjust properties as needed
            %%
            

            Features_result_nuevo = struct();
            Matriz_features_gesto_nuevo = 0;
            channel_ventana_gesto_nuevo = 0;
            
            % Define the COM port
            comPort = app.PuertoCOMDropDown.Value;
            baudratePort = app.BaudRateEditField.Value;
            
            
            % Create a serial object
            app.s = serial(comPort, 'BaudRate', baudratePort); % Use the same baud rate as in your Arduino code
            fopen(app.s);


            % Initialize a struct to store the data
            data_com = struct('data', []);
            
            % Flag to indicate whether to collect data
            collectData = false;
            
            % Define a timeout period in seconds
            timeoutInSeconds = app.TmuestraEditField.Value; % Set your desired timeout value here
            
            % Get the current time
            startTime = now;
            
            while true
                % Read one line of data
                dataLine = fgetl(app.s);
            
                % Check for end of data
                if strcmp(dataLine, 'END_DATA')
                    % End of data sequence
                    collectData = false;
                    
                    break; % Exit the loop when "END_DATA" is received

                elseif strcmp(dataLine, 'START_DATA')
                    % Start collecting data
                    collectData = true;
            
                    % Initialize the struct's data field
                    data_com.data = [];
                else
                    % Try to extract and store the data point
                    try
                        dataPoint = str2double(dataLine);
            
                        % Check if dataPoint is a valid number (not NaN)
                        if ~isnan(dataPoint)
                            % Append the data point to the struct's data field
                            data_com.data = [data_com.data, dataPoint];
                             % Add the data point to the real-time plot
                            
                            addpoints(hLine, datenum(now), dataPoint); % You can customize the x-axis value (e.g., time)
            
                            % Update the plot
                            drawnow limitrate; % Use 'limitrate' to limit the drawing rate
                        else
                            % Handle the case where the data is not a valid number
                            disp(['Invalid data in line: ', dataLine]);
                        end
                    catch
                        % Handle any errors that occur during data extraction
                        disp(['Error processing line: ', dataLine]);
                    end
                end
               
            
                  % Check for a timeout
                currentTime = now;
                if (currentTime - startTime) * 24 * 3600 > timeoutInSeconds
                    disp('Timeout: No data received for the specified time.');
                    break; % Exit the loop on timeout
                end
                
            end
            
            % Display the received data
            disp('Data received:');
            disp(data_com.data);


            app.datosgesto_nuevo = data_com.data;
            app.Fs_gesto_nuevo = 500;
                
           if app.vector_config(end) == 0 % TIEMPO CONTINUO 
               [vector_gesto_nuevo, ventanadatos_gesto_nuevo, ~] = FeaturesV2023(app.datosgesto_nuevo, 1, length(app.datosgesto_nuevo), 1, app.vector_config);
               % Hacer algo con las características extraídas de gesto_nuevo, por ejemplo, almacenarlas                
               Matriz_features_gesto_nuevo = vector_gesto_nuevo;
               channel_ventana_gesto_nuevo = ventanadatos_gesto_nuevo;
               Features_result_nuevo.Car_gesto_nuevo.VectorCaracteristicas_gesto_nuevo = vector_gesto_nuevo;                            
               Features_result_nuevo.Car_gesto_nuevo.Caracteristicas = app.vector_config;
               Features_result_nuevo.Car_gesto_nuevo.canales = 1;
               Features_result_nuevo.Car_gesto_nuevo.data = ventanadatos_gesto_nuevo;
               Features_result_nuevo.Car_gesto_nuevo.Ventana =  length(app.datosgesto_nuevo);
               Features_result_nuevo.Car_gesto_nuevo.Etiquetas_gesto_nuevo =  ones(length(Features_result_nuevo.Car_gesto_nuevo.VectorCaracteristicas_gesto_nuevo),1);
           else
               % ESTE SI REQUIERE DE LA Fs, FRECUENCIA
               [vector_gesto_nuevo, ventanadatos_gesto_nuevo, ~] = FeaturesV3023(app.datosgesto_nuevo,app.Fs_gesto_nuevo, 1, length(app.datosgesto_nuevo), 1, app.vector_config);
               % Hacer algo con las características extraídas de gesto_nuevo, por ejemplo, almacenarlas                
               Matriz_features_gesto_nuevo = vector_gesto_nuevo;
               channel_ventana_gesto_nuevo = ventanadatos_gesto_nuevo;
               Features_result_nuevo.Car_gesto_nuevo.VectorCaracteristicas_gesto_nuevo = vector_gesto_nuevo;                            
               Features_result_nuevo.Car_gesto_nuevo.Caracteristicas = app.vector_config;
               Features_result_nuevo.Car_gesto_nuevo.canales = 1;
               Features_result_nuevo.Car_gesto_nuevo.data = ventanadatos_gesto_nuevo;
               Features_result_nuevo.Car_gesto_nuevo.Ventana =  length(app.datosgesto_nuevo);
               Features_result_nuevo.Car_gesto_nuevo.Etiquetas_gesto_nuevo =  ones(length(Features_result_nuevo.Car_gesto_nuevo.VectorCaracteristicas_gesto_nuevo),1);
           end
           
           
           x_prueba = Features_result_nuevo.Car_gesto_nuevo.VectorCaracteristicas_gesto_nuevo;
           
           
           %% new logic
           net = app.loadedNet.Clasificador
           
           
           % Check the expected input size
            inputSize = net.inputs{1}.size;
            
            % Ensure x_prueba is a column vector
            % Assuming your loaded network is stored in the variable 'net'
           inputSize = net.inputs{1}.size;
           numFeatures = inputSize;  % Replace with the correct value
           x_prueba = reshape(x_prueba, [numFeatures, 1]);

           y_prueba = net(x_prueba);
           app.clase_prueba = vec2ind(y_prueba);
          
           app.ClaseEditField.Value = num2str(app.clase_prueba);
         
           
          % MOSTRAR GESTO
            if app.clase_prueba == 1
                % Set image for class 1
                image1 = imread('C:\Users\fersa\Desktop\UVG\Sem2_1\Estudio-Epilepsia-2023\extract_tool\Matlab_AutoExtract\imagenes\WristUp.jpg');
                app.Image2.ImageSource = image1;
            elseif app.clase_prueba == 2
                % Set image for class 2
                image2 = imread('C:\Users\fersa\Desktop\UVG\Sem2_1\Estudio-Epilepsia-2023\extract_tool\Matlab_AutoExtract\imagenes\WristDown.jpg');
                app.Image2.ImageSource = image2;
            else
                % Handle other cases if needed
                % For example, if clase_prueba is neither 1 nor 2
                % You can set a default image or perform some other action here
            end
           
           class = app.clase_prueba;
             
             
            % manejo seleccion de gestos
            if app.BrazoArribaAbajoButton.Value == 1;
            
                if class ==1
                    app.qtest = [pi/2, pi/6, pi/3, -pi/2, pi/3, -pi/3]'; 
                elseif class ==2
                    app.qtest = [pi/2, pi/6, pi/3, pi/2, -pi/3, -pi/3]';
                end
             else
                if class == 1
                    app.qtest = [pi/2, 0, pi, -pi/2, pi/3, -pi/3]';
                elseif class == 2
                    app.qtest = [0.1, pi/2, 0, pi/2, -pi/3, 0]';
                end
            end
            % configuracion inicio
            q0 = zeros(6, 1);
            
            % possible fields: {'pinv', 'dampedls', 'transpose'}
            Tdtest = robot_fkine(app.qtest);
            
            % seleccion interactiva
            method_ikine = app.MetodoDropDown.Value;
            [qpos,qpos_hist] = robot_ikine(Tdtest, q0, 'pos', method_ikine);
            
            figure(1);
            %% Simulacion
            mdl_puma560;
           
            % Plot on the axes
            plot(app.UIAxes, qpos_hist', 'LineWidth', 1);
            
            ylabel(app.UIAxes, '$\mathbf{q}_k$', 'Interpreter', 'latex', 'FontSize', 18);
            xlabel(app.UIAxes, '$k$', 'Interpreter', 'latex', 'FontSize', 18);
            grid(app.UIAxes, 'minor');
            
            % Animación del histórico

            subplot(1,1,1);
            p560.plot3d(q0');
            pause(0.5);
            p560.plot3d(qpos');
            
            % Close the serial connection
            fclose(app.s);

            clear Tdtest app.datosgesto_nuevo Features_result_nuevo Matriz_features_gesto_nuevo channel_ventana_gesto_nuevo data_com currentTime startTime collectData timeoutInSeconds 
            cla(ax);
             % Clear variables
           end
        end

        % Button pushed function: CargarEntrenamientoButton
        function CargarEntrenamientoButtonPushed2(app, event)
            [file, path] = uigetfile('*.mat', 'Seleccionar entrenamiento');
        
            % Check if the user canceled the file selection
            if file == 0
                return;  % User clicked the Cancel button.
            end
        
            % Load the MAT file
            fullFileName = fullfile(path, file);
            loadedData = load(fullFileName);
        
            % Check if the loaded data contains Clasificador_Entrenado
            if isfield(loadedData, 'Clasificador_Entrenado')
                % The loaded data contains a Clasificador_Entrenado struct
                Clasificador_Entrenado = loadedData.Clasificador_Entrenado;
            else 
                disp('Usuario canceló operación');
            end
           
            app.loadedNet = loadedData.Clasificador_Entrenado;
        end

        % Button pushed function: DesconectarButton
        function DesconectarButtonPushed(app, event)
            % Close the serial connection
            fclose(app.s);
            app.continueLoop = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.BitalinoBluetoothTab = uitab(app.TabGroup);
            app.BitalinoBluetoothTab.Title = 'Bitalino Bluetooth';
            app.BitalinoBluetoothTab.ButtonDownFcn = createCallbackFcn(app, @BitalinoBluetoothTabButtonDown, true);
            app.PaneldeControl = uipanel(app.BitalinoBluetoothTab);
            app.PaneldeControl.Title = 'Panel de Control';
            app.ParametersPanel = uipanel(app.PaneldeControl);
            app.ParametersPanel.Title = 'Parameters Panel';
            app.SPEEDEditFieldLabel = uilabel(app.ParametersPanel);
            app.SPEEDEditFieldLabel.Text = 'SPEED';
            app.SPEEDEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.SPEEDEditField.Limits = [5000 25000];
            app.SPEEDEditField.RoundFractionalValues = 'on';
            app.SPEEDEditField.ValueChangedFcn = createCallbackFcn(app, @SPEEDEditFieldValueChanged, true);
            app.SPEEDEditField.Value = 10000;
            app.COMDropDownLabel = uilabel(app.ParametersPanel);
            app.COMDropDownLabel.Text = 'COM';
            app.COMDropDown = uidropdown(app.ParametersPanel);
            app.COMDropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8', 'COM9'};
            app.COMDropDown.ValueChangedFcn = createCallbackFcn(app, @COMDropDownValueChanged, true);
            app.COMDropDown.Value = 'COM1';
            app.ButtonsPanel = uipanel(app.PaneldeControl);
            app.ButtonsPanel.Title = 'Buttons Panel';
            app.STARTButton = uibutton(app.ButtonsPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.STOPButton = uibutton(app.ButtonsPanel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.LampGreen = uilamp(app.ButtonsPanel);
            app.LampRed = uilamp(app.ButtonsPanel);
            app.IniciarButton = uibutton(app.PaneldeControl, 'push');
            app.IniciarButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarButtonPushed, true);
            app.IniciarButton.Text = 'Iniciar ';
            app.TiempodemuestraEditFieldLabel = uilabel(app.PaneldeControl);
            app.TiempodemuestraEditFieldLabel.Text = 'Tiempo de muestra';
            app.TiempodemuestraEditField = uieditfield(app.PaneldeControl, 'numeric');
            app.TiempodemuestraEditField.Limits = [1 Inf];
            app.TiempodemuestraEditField.Value = 3;
            app.ThSpinnerLabel = uilabel(app.PaneldeControl);
            app.ThSpinnerLabel.Text = 'Th';
            app.ThSpinner = uispinner(app.PaneldeControl);
            app.ThSpinner.Step = 0.01;
            app.ThSpinner.Limits = [0 0.5];
            app.ThSpinner.Value = 0.2;
            app.Lamp = uilamp(app.PaneldeControl);
            app.Lamp_2 = uilamp(app.PaneldeControl);
            app.ConectarBitalinoSwitchLabel = uilabel(app.PaneldeControl);
            app.ConectarBitalinoSwitchLabel.Text = 'Conectar Bitalino';
            app.ConectarBitalinoSwitch = uiswitch(app.PaneldeControl, 'slider');
            app.ConectarBitalinoSwitch.ValueChangedFcn = createCallbackFcn(app, @ConectarBitalinoSwitchValueChanged2, true);
            app.EmpezarStreamingSwitchLabel = uilabel(app.PaneldeControl);
            app.EmpezarStreamingSwitchLabel.Text = 'Empezar Streaming';
            app.EmpezarStreamingSwitch = uiswitch(app.PaneldeControl, 'slider');
            app.EmpezarStreamingSwitch.ValueChangedFcn = createCallbackFcn(app, @EmpezarStreamingSwitchValueChanged, true);
            app.NombreBluetoothBitalinoEditFieldLabel = uilabel(app.PaneldeControl);
            app.NombreBluetoothBitalinoEditFieldLabel.Text = 'Nombre Bluetooth Bitalino';
            app.NombreBluetoothBitalinoEditField = uieditfield(app.PaneldeControl, 'text');
            app.NombreBluetoothBitalinoEditField.Value = 'BITalino-00-97';
            app.PararButton = uibutton(app.PaneldeControl, 'push');
            app.PararButton.ButtonPushedFcn = createCallbackFcn(app, @PararButtonPushed, true);
            app.PararButton.Text = 'Parar';
            app.EscogerEntrenamientoButton = uibutton(app.PaneldeControl, 'push');
            app.EscogerEntrenamientoButton.ButtonPushedFcn = createCallbackFcn(app, @EscogerEntrenamientoButtonPushed, true);
            app.EscogerEntrenamientoButton.Text = 'Escoger Entrenamiento';
            app.Button = uibutton(app.PaneldeControl, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed2, true);
            app.Button.Icon = 'BotonPausa.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.PaneldeControl, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'play.png';
            app.Button_2.Text = '';
            app.BiopacTab = uitab(app.TabGroup);
            app.BiopacTab.Title = 'Biopac';
            app.BiopacTab.ButtonDownFcn = createCallbackFcn(app, @BiopacTabButtonDown, true);
            app.VectorCaractersticasEMGEEGLabel = uilabel(app.BiopacTab);
            app.VectorCaractersticasEMGEEGLabel.Text = 'Vector Características EMG/EEG';
            app.SeleccionarclasificadorButtonGroup = uibuttongroup(app.BiopacTab);
            app.SeleccionarclasificadorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SeleccionarclasificadorButtonGroupSelectionChanged, true);
            app.SeleccionarclasificadorButtonGroup.Title = 'Seleccionar Clasificador';
            app.RedNeuronalButton = uitogglebutton(app.SeleccionarclasificadorButtonGroup);
            app.RedNeuronalButton.Text = 'Red Neuronal';
            app.RedNeuronalButton.Value = true;
            app.SVMButton = uitogglebutton(app.SeleccionarclasificadorButtonGroup);
            app.SVMButton.Text = 'SVM';
            app.ClasificadorRedNeuronalLabel = uilabel(app.BiopacTab);
            app.ClasificadorRedNeuronalLabel.Text = 'Clasificador Red Neuronal';
            app.EntrenamientoEditFieldLabel = uilabel(app.BiopacTab);
            app.EntrenamientoEditFieldLabel.Text = 'Entrenamiento';
            app.SegmentosEntrenamiento = uieditfield(app.BiopacTab, 'numeric');
            app.SegmentosEntrenamiento.Limits = [0 100];
            app.SegmentosEntrenamiento.ValueChangedFcn = createCallbackFcn(app, @SegmentosEntrenamientoValueChanged, true);
            app.SegmentosEntrenamiento.Value = 70;
            app.SegmentosValidacion = uieditfield(app.BiopacTab, 'numeric');
            app.SegmentosValidacion.Limits = [0 100];
            app.SegmentosValidacion.ValueChangedFcn = createCallbackFcn(app, @SegmentosValidacionValueChanged, true);
            app.SegmentosValidacion.Value = 20;
            app.ValidacionEditFieldLabel = uilabel(app.BiopacTab);
            app.ValidacionEditFieldLabel.Text = 'Validacion';
            app.SegmentosPrueba = uieditfield(app.BiopacTab, 'numeric');
            app.SegmentosPrueba.Limits = [0 100];
            app.SegmentosPrueba.ValueChangedFcn = createCallbackFcn(app, @SegmentosPruebaValueChanged, true);
            app.SegmentosPrueba.Value = 10;
            app.PruebaEditFieldLabel = uilabel(app.BiopacTab);
            app.PruebaEditFieldLabel.Text = 'Prueba';
            app.Label = uilabel(app.BiopacTab);
            app.Label.Text = '%';
            app.Label_2 = uilabel(app.BiopacTab);
            app.Label_2.Text = '%';
            app.Label_3 = uilabel(app.BiopacTab);
            app.Label_3.Text = '%';
            app.AlgoritmoRNADropDownLabel = uilabel(app.BiopacTab);
            app.AlgoritmoRNADropDownLabel.Text = 'Algoritmo RNA';
            app.AlgoritmoparaelentrenamientoDropDown = uidropdown(app.BiopacTab);
            app.AlgoritmoparaelentrenamientoDropDown.Items = {'trainscg', 'trainr', 'trainrp', 'traingd'};
            app.AlgoritmoparaelentrenamientoDropDown.Value = 'trainscg';
            app.CapasOcultasDropDownLabel = uilabel(app.BiopacTab);
            app.CapasOcultasDropDownLabel.Text = 'Capas Ocultas';
            app.CapasOcultasDropDown = uidropdown(app.BiopacTab);
            app.CapasOcultasDropDown.Items = {'2', '4', '8', '10', '12', '15'};
            app.CapasOcultasDropDown.Value = '2';
            app.MaquinaSoporteVectorialSVMLabel = uilabel(app.BiopacTab);
            app.MaquinaSoporteVectorialSVMLabel.Text = 'Maquina Soporte Vectorial (SVM)';
            app.ValidacinCruzadaLabel = uilabel(app.BiopacTab);
            app.ValidacinCruzadaLabel.Text = 'Validación Cruzada: ';
            app.KfoldsLabel = uilabel(app.BiopacTab);
            app.KfoldsLabel.Enable = 'off';
            app.KfoldsLabel.Text = 'K-folds';
            app.NmerodeParticionesKSpinner = uispinner(app.BiopacTab);
            app.NmerodeParticionesKSpinner.Limits = [2 10];
            app.NmerodeParticionesKSpinner.Enable = 'off';
            app.NmerodeParticionesKSpinner.Value = 2;
            app.KernelDropDownLabel = uilabel(app.BiopacTab);
            app.KernelDropDownLabel.Enable = 'off';
            app.KernelDropDownLabel.Text = 'Kernel';
            app.KernelaemplearDropDown = uidropdown(app.BiopacTab);
            app.KernelaemplearDropDown.Items = {'Gaussian', 'Linear', ''};
            app.KernelaemplearDropDown.Enable = 'off';
            app.KernelaemplearDropDown.Value = 'Gaussian';
            app.CargarmatButton = uibutton(app.BiopacTab, 'push');
            app.CargarmatButton.ButtonPushedFcn = createCallbackFcn(app, @CargarmatButtonPushed, true);
            app.CargarmatButton.Text = 'Cargar.mat';
            app.EntrenarButton = uibutton(app.BiopacTab, 'push');
            app.EntrenarButton.ButtonPushedFcn = createCallbackFcn(app, @EntrenarButtonPushed, true);
            app.EntrenarButton.Text = 'Entrenar';
            app.ExactituddelclasificadorLabel = uilabel(app.BiopacTab);
            app.ExactituddelclasificadorLabel.Text = 'Exactitud Clasificador:  ?';
            app.GuardarButton = uibutton(app.BiopacTab, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.EpochsEditFieldLabel = uilabel(app.BiopacTab);
            app.EpochsEditFieldLabel.Text = 'Epochs';
            app.EpochsEditField = uieditfield(app.BiopacTab, 'numeric');
            app.EpochsEditField.Limits = [1 500];
            app.EpochsEditField.Value = 50;
            app.LearningRateEditFieldLabel = uilabel(app.BiopacTab);
            app.LearningRateEditFieldLabel.Text = 'Learning Rate';
            app.LearningRateEditField = uieditfield(app.BiopacTab, 'numeric');
            app.LearningRateEditField.Limits = [0.0001 0.1];
            app.LearningRateEditField.Value = 0.001;
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.SealesenTiempoRealBitalinoTab = uitab(app.TabGroup2);
            app.SealesenTiempoRealBitalinoTab.Title = 'Señales en Tiempo Real - Bitalino';
            app.SealesentiempoRealPanel = uipanel(app.SealesenTiempoRealBitalinoTab);
            app.SealesentiempoRealPanel.Title = 'Señales en tiempo Real';
            app.figFpz_2 = uiaxes(app.SealesentiempoRealPanel);
            title(app.figFpz_2, {'Canal 1 '; ''})
            xlabel(app.figFpz_2, 't (s)')
            ylabel(app.figFpz_2, 'V')
            app.Canal1Label = uilabel(app.SealesentiempoRealPanel);
            app.Canal1Label.Text = 'Canal 1';
            app.ZeroCrossingEditFieldLabel = uilabel(app.SealesentiempoRealPanel);
            app.ZeroCrossingEditFieldLabel.Text = 'Zero Crossing';
            app.ZeroCrossingEditField = uieditfield(app.SealesentiempoRealPanel, 'numeric');
            app.MAVEditFieldLabel = uilabel(app.SealesentiempoRealPanel);
            app.MAVEditFieldLabel.Text = 'MAV';
            app.MAVEditField = uieditfield(app.SealesentiempoRealPanel, 'numeric');
            app.KurtossisEditFieldLabel = uilabel(app.SealesentiempoRealPanel);
            app.KurtossisEditFieldLabel.Text = 'Kurtossis';
            app.KurtossisEditField = uieditfield(app.SealesentiempoRealPanel, 'numeric');
            app.AverageEnergyEditFieldLabel = uilabel(app.SealesentiempoRealPanel);
            app.AverageEnergyEditFieldLabel.Text = 'Average Energy';
            app.AverageEnergyEditField = uieditfield(app.SealesentiempoRealPanel, 'numeric');
            app.VarianzaEditFieldLabel = uilabel(app.SealesentiempoRealPanel);
            app.VarianzaEditFieldLabel.Text = 'Varianza';
            app.VarianzaEditField = uieditfield(app.SealesentiempoRealPanel, 'numeric');
            app.WilsonAmplitudEditFieldLabel = uilabel(app.SealesentiempoRealPanel);
            app.WilsonAmplitudEditFieldLabel.Text = 'Wilson Amplitud';
            app.WilsonAmplitudEditField = uieditfield(app.SealesentiempoRealPanel, 'numeric');
            app.MediaCuadraticaEditFieldLabel = uilabel(app.SealesentiempoRealPanel);
            app.MediaCuadraticaEditFieldLabel.Text = 'Media Cuadratica';
            app.MediaCuadraticaEditField = uieditfield(app.SealesentiempoRealPanel, 'numeric');
            app.Lamp_3 = uilamp(app.SealesentiempoRealPanel);
            app.EnpausaLabel = uilabel(app.SealesentiempoRealPanel);
            app.EnpausaLabel.Text = 'En pausa';
            app.Image = uiimage(app.SealesentiempoRealPanel);
            app.ClaseLabel = uilabel(app.SealesentiempoRealPanel);
            app.ClaseLabel.Text = 'Clase';
            app.SinActividadLabel = uilabel(app.SealesentiempoRealPanel);
            app.SinActividadLabel.Text = 'Sin Actividad';
            app.RobotBiopacTab = uitab(app.TabGroup2);
            app.RobotBiopacTab.Title = 'Robot - Biopac';
            app.GestoActividadLabel = uilabel(app.RobotBiopacTab);
            app.GestoActividadLabel.Text = 'Gesto - Actividad';
            app.SIMULADORBCI2023Label = uilabel(app.RobotBiopacTab);
            app.SIMULADORBCI2023Label.Text = 'SIMULADOR BCI - 2023';
            app.ClaseEditFieldLabel = uilabel(app.RobotBiopacTab);
            app.ClaseEditFieldLabel.Text = 'Clase';
            app.ClaseEditField = uieditfield(app.RobotBiopacTab, 'text');
            app.Image2 = uiimage(app.RobotBiopacTab);
            app.ControlOfflinePanel = uipanel(app.RobotBiopacTab);
            app.ControlOfflinePanel.Title = 'Control Offline';
            app.SealNuevaClasificadorButton = uibutton(app.ControlOfflinePanel, 'push');
            app.SealNuevaClasificadorButton.ButtonPushedFcn = createCallbackFcn(app, @SealNuevaClasificadorButtonPushed, true);
            app.SealNuevaClasificadorButton.Text = 'Señal Nueva & Clasificador';
            app.SimularButton = uibutton(app.ControlOfflinePanel, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed, true);
            app.SimularButton.Text = 'Simular';
            app.TiempoRealPanel = uipanel(app.RobotBiopacTab);
            app.TiempoRealPanel.Title = 'Tiempo Real';
            app.BaudRateEditFieldLabel = uilabel(app.TiempoRealPanel);
            app.BaudRateEditFieldLabel.Text = 'Baud Rate';
            app.BaudRateEditField = uieditfield(app.TiempoRealPanel, 'numeric');
            app.BaudRateEditField.Value = 38400;
            app.PuertoCOMDropDownLabel = uilabel(app.TiempoRealPanel);
            app.PuertoCOMDropDownLabel.Text = 'Puerto COM';
            app.PuertoCOMDropDown = uidropdown(app.TiempoRealPanel);
            app.PuertoCOMDropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8'};
            app.PuertoCOMDropDown.Value = 'COM3';
            app.ConectarButton = uibutton(app.TiempoRealPanel, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.ConectarButton.Text = 'Conectar ';
            app.CargarEntrenamientoButton = uibutton(app.TiempoRealPanel, 'push');
            app.CargarEntrenamientoButton.ButtonPushedFcn = createCallbackFcn(app, @CargarEntrenamientoButtonPushed2, true);
            app.CargarEntrenamientoButton.Text = 'Cargar Entrenamiento';
            app.DesconectarButton = uibutton(app.TiempoRealPanel, 'push');
            app.DesconectarButton.ButtonPushedFcn = createCallbackFcn(app, @DesconectarButtonPushed, true);
            app.DesconectarButton.Text = 'Desconectar';
            app.TmuestraLabel = uilabel(app.TiempoRealPanel);
            app.TmuestraLabel.Text = 'T. muestra';
            app.TmuestraEditField = uieditfield(app.TiempoRealPanel, 'numeric');
            app.TmuestraEditField.Value = 5;
            app.ParmetrosSimulacinPanel = uipanel(app.RobotBiopacTab);
            app.ParmetrosSimulacinPanel.Title = 'Parámetros Simulación';
            app.MetodoDropDownLabel = uilabel(app.ParmetrosSimulacinPanel);
            app.MetodoDropDownLabel.Text = 'Metodo';
            app.MetodoDropDown = uidropdown(app.ParmetrosSimulacinPanel);
            app.MetodoDropDown.Items = {'pinv', 'dampedls', 'transpose'};
            app.MetodoDropDown.Value = 'transpose';
            app.ConfiguracionesButtonGroup = uibuttongroup(app.ParmetrosSimulacinPanel);
            app.ConfiguracionesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ConfiguracionesButtonGroupSelectionChanged, true);
            app.ConfiguracionesButtonGroup.Title = 'Configuraciones';
            app.BrazoArribaAbajoButton = uiradiobutton(app.ConfiguracionesButtonGroup);
            app.BrazoArribaAbajoButton.Text = 'Brazo Arriba/Abajo';
            app.BrazoArribaAbajoButton.Value = true;
            app.BrazoExtendidoContraidoButton = uiradiobutton(app.ConfiguracionesButtonGroup);
            app.BrazoExtendidoContraidoButton.Text = 'Brazo Extendido/Contraido';
            app.TabGroup3 = uitabgroup(app.RobotBiopacTab);
            app.MuestreoSealTab = uitab(app.TabGroup3);
            app.MuestreoSealTab.Title = 'Muestreo - Señal';
            app.UIAxes2 = uiaxes(app.MuestreoSealTab);
            title(app.UIAxes2, 'Muestreo Señal - Tiempo Real')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.HistoricoTab = uitab(app.TabGroup3);
            app.HistoricoTab.Title = 'Historico';
            app.UIAxes = uiaxes(app.HistoricoTab);
            title(app.UIAxes, 'Historico P560')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed3, true);
            app.Button_3.Icon = 'goback.jpg';
            app.Button_3.Text = '';
            app.Panel = uipanel(app.UIFigure);
            app.Image4 = uiimage(app.Panel);
            app.Image4.ImageSource = 'banner.jpg';
            app.BCIEMGEEGUVGLabel = uilabel(app.Panel);
            app.BCIEMGEEGUVGLabel.Text = 'BCI EMG/EEG - UVG';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfaz_EMG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end