classdef RecoleccionDeDatosEMG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RegresarButton                  matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        BitalinoTab_2                   matlab.ui.container.Tab
        figFpz                          matlab.ui.control.UIAxes
        BiopacSignalTab                 matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        Gesto1Tab                       matlab.ui.container.Tab
        Canal1Label_2                   matlab.ui.control.Label
        figFpz_2                        matlab.ui.control.UIAxes
        Gesto2Tab                       matlab.ui.container.Tab
        Canal1Label                     matlab.ui.control.Label
        figFpz_3                        matlab.ui.control.UIAxes
        LarecoleccinestenPausaLabel     matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        InstruccionesTab                matlab.ui.container.Tab
        Label_7                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        InstruccionesparaelusodelaInterfazLabel  matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        BitalinoTab                     matlab.ui.container.Tab
        CaracteristicasSeleccionadasPanel  matlab.ui.container.Panel
        SeleccionartodoCheckBox         matlab.ui.control.CheckBox
        Lamp_3                          matlab.ui.control.Lamp
        Lamp_2                          matlab.ui.control.Lamp
        NombreBluetoothBitalinoEditField  matlab.ui.control.EditField
        NombreBluetoothBitalinoEditFieldLabel  matlab.ui.control.Label
        EmpezarStreamingSwitch          matlab.ui.control.Switch
        EmpezarStreamingSwitchLabel     matlab.ui.control.Label
        ConectarBitalinoSwitch          matlab.ui.control.Switch
        ConectarBitalinoSwitchLabel     matlab.ui.control.Label
        CrearTablaButton                matlab.ui.control.Button
        MediaCuadraticaCheckBox         matlab.ui.control.CheckBox
        AmplituddeWilsonCheckBox        matlab.ui.control.CheckBox
        VarianzaCheckBox                matlab.ui.control.CheckBox
        PromediodeEnergaCheckBox        matlab.ui.control.CheckBox
        KurtosisCheckBox                matlab.ui.control.CheckBox
        MAVCheckBox                     matlab.ui.control.CheckBox
        ZeroCrossingCheckBox            matlab.ui.control.CheckBox
        GrabacinPanel                   matlab.ui.container.Panel
        TerminarsesinButton             matlab.ui.control.Button
        Lamp_4                          matlab.ui.control.Lamp
        Button_2                        matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        Label_9                         matlab.ui.control.Label
        NmerodemuestrastomadasLabel     matlab.ui.control.Label
        IniciarsesinButton              matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        ClaseagrabarDropDown            matlab.ui.control.DropDown
        ClaseagrabarDropDownLabel       matlab.ui.control.Label
        NombreDelArchivoEditField       matlab.ui.control.EditField
        NombreDelArchivoEditFieldLabel  matlab.ui.control.Label
        TiempodemuestraatomarEditField  matlab.ui.control.NumericEditField
        TiempodemuestraatomarEditFieldLabel  matlab.ui.control.Label
        GuardarArchivoButton            matlab.ui.control.Button
        BiopacEMGTab                    matlab.ui.container.Tab
        CapturarSealesBiopacPanel       matlab.ui.container.Panel
        TabGroup5                       matlab.ui.container.TabGroup
        GrabarGuardarSealTab            matlab.ui.container.Tab
        CapturadesealesconBiopacygenerarxlsxLabel  matlab.ui.control.Label
        IniciarBSLAnalysisButton        matlab.ui.control.Button
        CrearPlantillaxlsxButton        matlab.ui.control.Button
        GenerarmatTab                   matlab.ui.container.Tab
        SeleccionarplantillasxlsxyextraerLabel  matlab.ui.control.Label
        ExtraermatsButton               matlab.ui.control.Button
        TextoTiempogesto_2              matlab.ui.control.Label
        TextoDimensionesgesto_2         matlab.ui.control.Label
        FreqMuestreoFs_gesto_2EditField  matlab.ui.control.NumericEditField
        FreqMuestreoFsLabel_2           matlab.ui.control.Label
        HzLabel_2                       matlab.ui.control.Label
        CargarMATgesto_2Button          matlab.ui.control.Button
        Gesto2EMGLabel                  matlab.ui.control.Label
        Gesto1EMGLabel                  matlab.ui.control.Label
        TextoTiempogesto_1              matlab.ui.control.Label
        TextoDimensionesgesto_1         matlab.ui.control.Label
        HzLabel                         matlab.ui.control.Label
        FreqMuestreoFs_gesto_1EditField  matlab.ui.control.NumericEditField
        FreqMuestreoFsLabel             matlab.ui.control.Label
        CargarMATgesto_1Button          matlab.ui.control.Button
        SeleccindeCaractersticasaExtraerPanel_2  matlab.ui.container.Panel
        PaneldeControlSegmentacion      matlab.ui.container.Panel
        SegmentarmatButton_2            matlab.ui.control.Button
        ExtraersegmentosmatsButton_2    matlab.ui.control.Button
        SegmentarButton                 matlab.ui.control.Button
        VentanaMnimaSpinner             matlab.ui.control.Spinner
        VentanaMnimaSpinnerLabel        matlab.ui.control.Label
        ThresholdInicialSpinner         matlab.ui.control.Spinner
        ThresholdInicialSpinnerLabel    matlab.ui.control.Label
        ThresholdFinalSpinner           matlab.ui.control.Spinner
        ThresholdFinalSpinnerLabel      matlab.ui.control.Label
        IniciarExtraccinButton          matlab.ui.control.Button
        SeleccionartodoCheckBox_2       matlab.ui.control.CheckBox
        TCDesviacinEstndarCheckBox      matlab.ui.control.CheckBox
        TCEnergaAcumuladaCheckBox       matlab.ui.control.CheckBox
        TCCurtosisCheckBox              matlab.ui.control.CheckBox
        TCValorMedioAbsolutoMAVCheckBox  matlab.ui.control.CheckBox
        TCCrucesporceroZCCheckBox       matlab.ui.control.CheckBox
        BiopacEEGTab                    matlab.ui.container.Tab
        SeleccindeCaractersticasaExtraerPanel  matlab.ui.container.Panel
        IniciarExtraccinButton_2        matlab.ui.control.Button
        PaneldeControlSegmentacion_2    matlab.ui.container.Panel
        SegmentarmatButton              matlab.ui.control.Button
        ExtraersegmentosmatsButton      matlab.ui.control.Button
        SegmentarButton_2               matlab.ui.control.Button
        VentanaMnimaSpinner_2           matlab.ui.control.Spinner
        VentanaMnimaSpinner_2Label      matlab.ui.control.Label
        ThresholdInicialSpinner_2       matlab.ui.control.Spinner
        ThresholdInicialSpinner_2Label  matlab.ui.control.Label
        ThresholdFinalSpinner_2         matlab.ui.control.Spinner
        ThresholdFinalSpinner_2Label    matlab.ui.control.Label
        TabGroup4                       matlab.ui.container.TabGroup
        TiempoContinuoTab               matlab.ui.container.Tab
        TCDesviacinEstndarCheckBox_2    matlab.ui.control.CheckBox
        TCEnergaAcumuladaCheckBox_2     matlab.ui.control.CheckBox
        TCCurtosisCheckBox_2            matlab.ui.control.CheckBox
        TCValorMedioAbsolutoMAVCheckBox_2  matlab.ui.control.CheckBox
        TCCrucesporceroZCCheckBox_2     matlab.ui.control.CheckBox
        SeleccionartodoCheckBox_4       matlab.ui.control.CheckBox
        FrecuenciaTab                   matlab.ui.container.Tab
        SeleccionartodoCheckBox_5       matlab.ui.control.CheckBox
        StdFreqCheckBox                 matlab.ui.control.CheckBox
        ratio_5CheckBox                 matlab.ui.control.CheckBox
        ratio_3CheckBox                 matlab.ui.control.CheckBox
        ratio_1CheckBox                 matlab.ui.control.CheckBox
        ratio_2CheckBox                 matlab.ui.control.CheckBox
        ratio_4CheckBox                 matlab.ui.control.CheckBox
        CapturarSealesBiopacPanel_2     matlab.ui.container.Panel
        CargarMATgesto_2Button_2        matlab.ui.control.Button
        CargarMATgesto_1Button_2        matlab.ui.control.Button
        FreqMuestreoFs_gesto_2EditField_2  matlab.ui.control.NumericEditField
        FreqMuestreoFsLabel_4           matlab.ui.control.Label
        FreqMuestreoFs_gesto_1EditField_2  matlab.ui.control.NumericEditField
        FreqMuestreoFsLabel_3           matlab.ui.control.Label
        TextoTiempogesto_4              matlab.ui.control.Label
        TextoDimensionesgesto_4         matlab.ui.control.Label
        HzLabel_4                       matlab.ui.control.Label
        Gesto2EMGLabel_2                matlab.ui.control.Label
        Gesto1EMGLabel_2                matlab.ui.control.Label
        TextoTiempogesto_3              matlab.ui.control.Label
        TextoDimensionesgesto_3         matlab.ui.control.Label
        HzLabel_3                       matlab.ui.control.Label
        TabGroup5_2                     matlab.ui.container.TabGroup
        GrabarGuardarSealTab_2          matlab.ui.container.Tab
        CapturadesealesconBiopacygenerarxlsxLabel_2  matlab.ui.control.Label
        IniciarBSLAnalysisButton_2      matlab.ui.control.Button
        CrearPlantillaxlsxButton_2      matlab.ui.control.Button
        GenerarmatTab_2                 matlab.ui.container.Tab
        SeleccionarplantillasxlsxyextraerLabel_2  matlab.ui.control.Label
        ExtraermatsButton_2             matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        DesconectadoLamp                matlab.ui.control.Lamp
        DesconectadoLampLabel           matlab.ui.control.Label
        RecoleccindeDatosEMGEEGUVGLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        T=0; % Description
        n=0;
        TC_Flag = 1;
        Fs_Flag = 0; 
        DataEnBrutoEMG = cell(0,0)
        Bit=0;
        F_pb = filtro_pasa_banda(1000,20,450);    %Diseñar filtro pasa banda
        F_notch = filtro_rechaza_banda(1000);     %Diseñar filtro rechaza banda
        data_n=0;
        BanderaParaEmpezar=0;
        NombreBit=0;
        flag_recibir1=0;
        flag_recibir2=0;
        datosgesto_1 = 0;
        datosgesto_2 = 0;
        
        datosEEG_Blink = 0;
        datosEEG_HeadDown = 0;
        
        flag_terminar=0;
        Pausa=0;
        Fs_gesto_1=0;
        Fs_gesto_2=0;
        FsEEG_Blink=0;
        FsEEG_HeadDown=0;
        canales = 1;
        ctrl2=0;
        time = 0;
        NMuestras=0;
        A = 0;
        muestras_gesto_1
        muestras_gesto_2
        muestras_Blink
        muestras_HeadDown
        s_ventana
        c_gesto_2 = 1;
        c_gesto_1 = 1;
        c_HeadDown = 1;
        c_Blink = 1;
        op %Vector de opciones
        Matriz_features_gesto_1
        channel_ventana_gesto_1
        Matriz_features_gesto_2
        channel_ventana_gesto_2
        Matriz_features_HeadDown
        channel_ventana_HeadDown
        Matriz_features_Blink
        channel_ventana_Blink
        segment_start_gesto_1
        segment_start_gesto_2
        segment_end_gesto_1
        segment_end_gesto_2
        emg_gesto_1
        emg_gesto_2
        ClassType = 2;
        rawgesto_1;
        rawgesto_2;
        gesto_sample;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.Lamp_2.Enable = "off";
            app.Lamp_3.Enable = "off";
            app.Lamp_4.Enable = "off";
            
        end

        % Button pushed function: CrearTablaButton
        function CrearTablaButtonPushed(app, event)
            
            app.BanderaParaEmpezar=0;
            app.ctrl2=0;
            
            app.T = table()
            if app.ZeroCrossingCheckBox.Value == 1
                app.T.ZC = zeros(0)
            end
            if app.MAVCheckBox.Value == 1
                app.T.MAV = zeros(0)
            end
            if app.KurtosisCheckBox.Value == 1
                app.T.Kurtosis = zeros(0)
            end
            if app.PromediodeEnergaCheckBox.Value == 1
                app.T.AverageE = zeros(0)
            end
            
            if app.VarianzaCheckBox.Value == 1
                app.T.Varianza = zeros(0)
            end
            if app.AmplituddeWilsonCheckBox.Value == 1
                app.T.AmplitudWilson = zeros(0)
            end
            if app.MediaCuadraticaCheckBox.Value == 1
                app.T.MediaCuadratica = zeros(0)
            end
        end

        % Callback function
        function TomarMuestraButtonPushed(app, event)


        end

        % Button pushed function: GuardarArchivoButton
        function GuardarArchivoButtonPushed(app, event)
            
            Clase = app.ClaseagrabarDropDown.Value;
            NombreA = app.NombreDelArchivoEditField.Value
            writetable(app.T,string(NombreA)+ Clase +"."+'xls')
            writecell(app.DataEnBrutoEMG,string(NombreA)+"_"+string(Clase)+"EnBrutoEMG"+"."+'xls')
        end

        % Callback function
        function RegresarButtonPushed(app, event)
            
            %b.stopBackground
            
            Interfaz_Inicio
            delete(app.Bit)
            clear all
        end

        % Value changed function: EmpezarStreamingSwitch
        function EmpezarStreamingSwitchValueChanged(app, event)
            value = app.EmpezarStreamingSwitch.Value;
            if strcmp( value,'On')
                
                app.flag_recibir2 =1;
                startBackground(app.Bit)
                app.Lamp_3.Enable="on";
                
                
            else
                app.flag_recibir2 =0;
                stopBackground(app.Bit)
                app.Lamp_3.Enable="off";
            end


        end

        % Value changed function: ConectarBitalinoSwitch
        function ConectarBitalinoSwitchValueChanged(app, event)
            value = app.ConectarBitalinoSwitch.Value;
            
            if strcmp( value,'On')
                app.NombreBit = app.NombreBluetoothBitalinoEditField.Value
                app.Bit = Bitalino(char(app.NombreBit)); %Creamos la conexion con el dispositivo bitalino y esa conexion la volvemos un objeto.
                app.flag_recibir1=1;
                app.Lamp_2.Enable="on"
            else
                app.Lamp_2.Enable="off"
                app.flag_recibir1=0;
                
                
                clear all


            end
        end

        % Button pushed function: IniciarsesinButton
        function IniciarsesinButtonPushed(app, event)
            %Extracción características


            app.ctrl2=0;
            app.time = 0;
            app.flag_terminar=0;
            app.NMuestras=0;
            app.BanderaParaEmpezar=0;
            
            app.n=0;
            
            Tiempo_DMuestra = app.TiempodemuestraatomarEditField.Value;
            Lenght_DataEnBruto= (Tiempo_DMuestra*1000)-300;
            volt = 0;
            
            % Pause to acquire data for 20 seconds
            %pause(10);
            % Read the data from the device


            while (app.flag_recibir2 ==1) && (app.flag_recibir1==1)&& (app.flag_terminar==0)
                
                
                while (app.time < Tiempo_DMuestra)      %la variable m determina el tiempoque nosotros asignaramos para llenar el buffer
                    pause(1)   % se le da una pausa de 1 segundo por cada vez que pase, para que se logre llenar el buffer en el tiempo que se requiere
                    app.time = app.time + 1; %variable que aumenta cada 1 segundo.
                    
                    app.Lamp_4.Enable = 'on';
                    
                    
                end
                
                
                app.Lamp_4.Enable = 'off';
                
                
                data = read(app.Bit);% tomamos los ultimos valores en el buffer.\
                
                app.time = 0; %se reinicia el contador para la siguiente vez que se llene el buffer
                EMG_raw = data(:,6); %se toma solo la columna de la senal EMG
                EMG = EMG_raw*(3.3/1024);%se convierte la senal a la escala de 3.3 V
                data_f = filter(app.F_pb, EMG');                          %Aplicar filtro pasa bandas
                app.data_n = filter(app.F_notch, data_f);                       %Aplicar filtro notch
                app.BanderaParaEmpezar=app.BanderaParaEmpezar+1;


                ylim(app.figFpz, [min(app.data_n) max(app.data_n)]);
                Epocas = size(app.data_n,2) ; %se toma el tamano del buffer obtenido
                L = Epocas; %longitud del vector epocas
                
                t=linspace(0, Epocas/1000, L);
                h1 = plot(app.figFpz, t, app.data_n, 'Color','r') %se plotea esa muestra


                if (app.Pausa == 0)&&(app.BanderaParaEmpezar > 2)
                    app.NMuestras = app.NMuestras +1;
                    app.Label_9.Text = string(app.NMuestras);
                    app.n = app.n+1
                    app.ctrl2 = app.ctrl2 + 1;     
                    app.DataEnBrutoEMG(:,app.ctrl2)= num2cell(app.data_n(1:Lenght_DataEnBruto));
                    
                    if app.ZeroCrossingCheckBox.Value == 1
                        app.T.ZC(app.n) = jZeroCrossing(app.data_n,0.1);
                        
                    end
                    if app.MAVCheckBox.Value == 1
                        app.T.MAV(app.n) = jMeanAbsoluteDeviation(app.data_n);
                        
                    end
                    if app.KurtosisCheckBox.Value == 1
                        app.T.Kurtosis(app.n) = jKurtosis(app.data_n);
                        
                    end
                    if app.PromediodeEnergaCheckBox.Value == 1
                        app.T.AverageE(app.n) = jAverageEnergy(app.data_n);
                        
                    end
                    
                    if app.VarianzaCheckBox.Value == 1
                        app.T.Varianza(app.n) = jVariance(app.data_n);
                        
                    end
                    if app.AmplituddeWilsonCheckBox.Value == 1
                        app.T.AmplitudWilson(app.n) = jWillisonAmplitude(app.data_n,0.1);
                        
                    end
                    if app.MediaCuadraticaCheckBox.Value == 1
                        app.T.MediaCuadratica(app.n) = jRootMeanSquare(app.data_n);
                        
                    end
                    app.LarecoleccinestenPausaLabel.Visible = 'off';
                end
                if app.Pausa == 1
                    app.LarecoleccinestenPausaLabel.Visible = 'on';
                end
                
            end
        end

        % Value changed function: ClaseagrabarDropDown
        function ClaseagrabarDropDownValueChanged(app, event)
            value = app.ClaseagrabarDropDown.Value;
            switch value
                case 'Wrist-Up'
                    app.Image.ImageSource = imread("WristUp.jpg");
                case 'Wrist-Down'
                    app.Image.ImageSource = imread("manita.jfif");
                    
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            app.Pausa = 1;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            
            app.Pausa =0;
        end

        % Callback function
        function Button_3Pushed(app, event)
            app.LarecoleccinestenPausaLabel.Visible = 'on';
        end

        % Value changed function: SeleccionartodoCheckBox
        function SeleccionartodoCheckBoxValueChanged(app, event)
            value = app.SeleccionartodoCheckBox.Value;
            
            if app.SeleccionartodoCheckBox.Value ==1
                
                app.ZeroCrossingCheckBox.Value=1;
                app.MAVCheckBox.Value=1;
                app.KurtosisCheckBox.Value=1;
                app.PromediodeEnergaCheckBox.Value=1;
                app.VarianzaCheckBox.Value=1;
                app.AmplituddeWilsonCheckBox.Value=1;
                app.MediaCuadraticaCheckBox.Value=1;
            end
            if app.SeleccionartodoCheckBox.Value ==0
                
                app.ZeroCrossingCheckBox.Value=0;
                app.MAVCheckBox.Value=0;
                app.KurtosisCheckBox.Value=0;
                app.PromediodeEnergaCheckBox.Value=0;
                app.VarianzaCheckBox.Value=0;
                app.AmplituddeWilsonCheckBox.Value=0;
                app.MediaCuadraticaCheckBox.Value=0;
            end
        end

        % Button pushed function: TerminarsesinButton
        function TerminarsesinButtonPushed(app, event)
            app.flag_terminar=1;
            app.n=0;
            app.ctrl2=0;
            app.time = 0;
            app.NMuestras=0;
            app.BanderaParaEmpezar=0;
            app.T = table();
            a.data_n=0;
        
            if app.Pausa == 1
                app.LarecoleccinestenPausaLabel.Visible = 'off';
                app.Pausa=0;
            end
            
        end

        % Button pushed function: IniciarExtraccinButton
        function IniciarExtraccinButtonPushed(app, event)
            % Mensaje de advertencia 
            message = sprintf('Esta operación puede tomar algunos minutos');
            %uialert(app.ClasificadorAnotacionesUIFigure,message,'Aviso',...
            %'Icon','warning');          
            Features_result = struct();
            Features_result.Car_gesto_1.VectorCaracteristicas_gesto_1 = [];
            Features_result.Car_gesto_2.VectorCaracteristicas_gesto_2 = [];
            Features_result.Car_gesto_1.data = [];
            Features_result.Car_gesto_2.data = [];
            app.Matriz_features_gesto_1 = 0;
            app.channel_ventana_gesto_1 = 0;
            app.Matriz_features_gesto_2 = 0;
            app.channel_ventana_gesto_2 = 0;
            
            
            app.c_gesto_1 = 1; 
            app.c_gesto_2 = 1;
            %Se elige si es analisis de TC
            app.op =[0,0,0,0,0,0];                              
            app.canales = 1; %Por el momento, sólo se usa 1 canal de cada señal                      
            
            seleccion1 = '';seleccion2 = '';seleccion3 = '';seleccion4 = '';seleccion5 = '';
            if app.TCDesviacinEstndarCheckBox.Value
                app.op(1) =1;
                seleccion1= 'DesviaciónEstándar'; 
            end
            if app.TCCurtosisCheckBox.Value
                app.op(2) =1;
                seleccion2= 'Curtosis'; 
            end
            if app.TCCrucesporceroZCCheckBox.Value
                app.op(3) =1;
                seleccion3= 'CrucesPorCero'; 
            end
            if app.TCValorMedioAbsolutoMAVCheckBox.Value
                app.op(4) =1;
                seleccion4= 'ValorMedioAbsoluto'; 
            end
            if app.TCEnergaAcumuladaCheckBox.Value
               app.op(5) =1;
               seleccion5= 'EnergíaAcumulada'; 
            end 
            
            % Variables de segmentacion
            lower_threshold = app.ThresholdInicialSpinner.Value;
            upper_threshold = app.ThresholdFinalSpinner.Value;
            min_segment_length = app.VentanaMnimaSpinner.Value;
            
            %% Segmentation
            [segments, app.emg_gesto_1, app.segment_start_gesto_1, app.segment_end_gesto_1]= SegmentV2(app.datosgesto_1, app.Fs_gesto_1, lower_threshold, upper_threshold, min_segment_length, 0);

             i = 1;
             while i <= length(app.segment_start_gesto_1)
                    % Extraer características utilizando FeaturesV2
                    [vector_gesto_1, ventanadatos_gesto_1, ~] = FeaturesV2023(app.emg_gesto_1(app.segment_start_gesto_1(i):app.segment_end_gesto_1(i)), app.canales, app.segment_end_gesto_1(i) - app.segment_start_gesto_1(i) + 1, app.c_gesto_1, app.op);
                    % Hacer algo con las características extraídas de gesto_1, por ejemplo, almacenarlas                
                    app.Matriz_features_gesto_1 = vector_gesto_1;
                    app.channel_ventana_gesto_1 = ventanadatos_gesto_1;
                    Features_result.Car_gesto_1.VectorCaracteristicas_gesto_1 = [Features_result.Car_gesto_1.VectorCaracteristicas_gesto_1; vector_gesto_1];                            
                    Features_result.Car_gesto_1.Caracteristicas = [seleccion1,',',seleccion2,',',seleccion3,',',seleccion4,',',seleccion5];
                    Features_result.Car_gesto_1.canales = app.c_gesto_1;
                    Features_result.Car_gesto_1.data = [Features_result.Car_gesto_1.data; ventanadatos_gesto_1];
                    Features_result.Car_gesto_1.FMuestreo = app.Fs_gesto_1;
                    Features_result.Car_gesto_1.Ventana =  app.segment_end_gesto_1 - app.segment_start_gesto_1 + 1;
                    Features_result.Car_gesto_1.Etiquetas_gesto_1 =  ones(length(Features_result.Car_gesto_1.VectorCaracteristicas_gesto_1),1);
                    % Move to the next segment
                    i = i + 1;
            end
             
            % Segmentation
            [segments_2, app.emg_gesto_2, app.segment_start_gesto_2, app.segment_end_gesto_2]= SegmentV2(app.datosgesto_2, app.Fs_gesto_1, lower_threshold, upper_threshold, min_segment_length, 0);

            i = 1;
            while i <= length(app.segment_start_gesto_2)
                % Extraer características utilizando FeaturesV2 para gesto_2
                [vector_gesto_2,ventanadatos_gesto_2, ~] = FeaturesV2023(app.emg_gesto_2(app.segment_start_gesto_2(i):app.segment_end_gesto_2(i)), app.canales, app.segment_end_gesto_2(i) - app.segment_start_gesto_2(i) + 1, app.c_gesto_2, app.op);
               
                % Hacer algo con las características extraídas de gesto_2, por ejemplo, almacenarlas
                app.Matriz_features_gesto_2 = vector_gesto_2;
                app.channel_ventana_gesto_2 = ventanadatos_gesto_2;
                Features_result.Car_gesto_2.VectorCaracteristicas_gesto_2 = [Features_result.Car_gesto_2.VectorCaracteristicas_gesto_2; vector_gesto_2];
                Features_result.Car_gesto_2.Caracteristicas = [seleccion1,',',seleccion2,',',seleccion3,',',seleccion4,',',seleccion5];
                Features_result.Car_gesto_2.canales = app.c_gesto_2;
                Features_result.Car_gesto_2.data = [Features_result.Car_gesto_2.data; ventanadatos_gesto_2];
                Features_result.Car_gesto_2.FMuestreo = app.Fs_gesto_2;
                Features_result.Car_gesto_2.Ventana =  app.segment_end_gesto_2 - app.segment_start_gesto_2 + 1;
                Features_result.Car_gesto_2.Etiquetas_gesto_2 = 2*ones(length(Features_result.Car_gesto_2.VectorCaracteristicas_gesto_2),1);
                % Move to the next segment
                i = i + 1;
           end
      
   
            Features_result.Tipo_Clasificacion = app.ClassType;
            Features_result.Vector_Caracteristicas = app.op;
            Features_result.Tipo_Caracteristicas = 0;
        
            %Guardar archivo MAT
            startingFolder = 'C:\Program Files\MATLAB'
            if ~exist(startingFolder, 'dir')
                startingFolder = pwd;
            end
            % Put in the name of the mat file that the user wants to save.
            % defaultFileName = fullfile(startingFolder, '*.mat')
            [file,path] = uiputfile('VectorFeatures.mat','Save file name');
            if file == 0
            % User clicked the Cancel button.
             return;
            end
            
            %AQUI ESTABA LO DEL STRUCT DE FEATURES ANTES
            
            %Guardar archivo en la ruta seleccionada
            fullFileName = fullfile(path, file);            
            save(fullFileName,'Features_result')
            
            %Mensaje de exito 
            message = sprintf(['Archivo ',file,' guardado exitosamente']);
            %uialert(app.ClasificadorAnotacionesUIFigure,message,'Aviso',...
            %'Icon','success');         
        
        end

        % Button pushed function: CargarMATgesto_1Button
        function CargarMATgesto_1ButtonPushed(app, event)

            %Seleccionar archivo obtenido de base de datos
            [file,path,~] = uigetfile({'*.mat'}, 'Seleccionar archivo .mat');
            %Si no se hace seleccion
            if isequal(file,0)
                 disp('Usuario canceló operación');
            else
                disp(['EL usuario selecccionó ', fullfile(path,file)]);
                %Asegurarse que el workspace cargado contenga la variable con
                %el nombre 'gesto_1'
                workspace = load(strcat(path,file),'gesto_1');
                %Cargamos la señal EMG a la variable de datos respectiva
                app.datosgesto_1 = workspace.gesto_1.data;
                app.rawgesto_1 = workspace.gesto_1;
                %Cargamos la frecuencia de muestreo de los datos, se muestra en
                %el label por si se necesita modificar
                app.Fs_gesto_1 = workspace.gesto_1.sampling_frequency;
                app.FreqMuestreoFs_gesto_1EditField.Value = app.Fs_gesto_1;
                
                %Obtenemos el tamano del struct 
                datasize = size(app.datosgesto_1);
                %Se indica en el texto derecho el tamaño y duración de los datos a emplear
                app.TextoDimensionesgesto_1.Text = append("Tamaño: ",num2str(datasize(1))," x ",num2str(datasize(2)));
                segundos = workspace.gesto_1.data_length_sec ;                               
                d = round((segundos/86400));
                if (d>segundos/86400)
                    d = d-1; 
                end
                s_temp = segundos-d*86400;
                h = round((s_temp/3600));
                if (h>s_temp/3600)
                    h = h-1; 
                end
                m =round(((s_temp/3600)-h)*60);
                if (m>((s_temp/3600)-h)*60)
                    m = m-1; 
                end
                s = s_temp-(h*3600)-m*60;
                if(d~=0)
                    app.TextoTiempogesto_1.Text = append("Duración: ",num2str(d)," Día, ",num2str(h)," h ",...
                                   num2str(m)," m ",num2str(s)," s");
                else
                    if(h~=0)
                        app.TextoTiempogesto_1.Text = append("Duración: ",num2str(h)," h ",...
                                   num2str(m)," m ",num2str(s)," s");
                    else
                        if(m~=0)
                            app.TextoTiempogesto_1.Text = append("Duración: ",num2str(m)," m ",num2str(s)," s");
                        else
                            app.TextoTiempogesto_1.Text = append("Duración: ",num2str(s)," s");
                        end
                    end
                end
                ylim(app.figFpz_2, [min(app.datosgesto_1) max(app.datosgesto_1)]);
                Epocas = size(app.datosgesto_1,2) ; %se toma el tamano del buffer obtenido
                L = Epocas; %longitud del vector epocas
                
                t=linspace(0, Epocas/1000, L);
                h1 = plot(app.figFpz_2, t, app.datosgesto_1, 'Color','r') %se plotea esa muestra
            end
            
            % Get a handle to the target tab group
            targetTabGroup = app.TabGroup3; % Replace with the actual name
            
            % Set the SelectedTab property to the desired tab
            targetTabGroup.SelectedTab = app.Gesto1Tab; % Replace with the actual tab name

            % AUN NO SE SI IMPLEMENTAR
            %app.canales_listadoEEG = app.Canales_gesto_1disponiblesListBox.Items;
        end

        % Button pushed function: CargarMATgesto_2Button
        function CargarMATgesto_2ButtonPushed(app, event)
            
            %Seleccionar archivo obtenido de base de datos
            [file,path,~] = uigetfile({'*.mat'}, 'Seleccionar archivo .mat');
            %Si no se hace seleccion
            if isequal(file,0)
                 disp('Usuario canceló operación');
            else
                disp(['EL usuario selecccionó ', fullfile(path,file)]);
                %Asegurarse que el workspace cargado contenga la variable con
                %el nombre 'gesto_2'
                workspace = load(strcat(path,file),'gesto_2');
                %Cargamos la señal EMG a la variable de datos respectiva
                app.datosgesto_2 = workspace.gesto_2.data;
                app.rawgesto_2 = workspace.gesto_2;
                %Cargamos la frecuencia de muestreo de los datos, se muestra en
                %el label por si se necesita modificar
                app.Fs_gesto_2 = workspace.gesto_2.sampling_frequency;
                app.FreqMuestreoFs_gesto_2EditField.Value = app.Fs_gesto_2;

                %Obtenemos el tamano del struct 
                datasize = size(app.datosgesto_2);
                %Se indica en el texto derecho el tamaño y duración de los datos a emplear
                app.TextoDimensionesgesto_2.Text = append("Tamaño: ",num2str(datasize(1))," x ",num2str(datasize(2)));
                segundos = workspace.gesto_2.data_length_sec;                               
                d = round((segundos/86400));
                if (d>segundos/86400)
                    d = d-1; 
                end
                s_temp = segundos-d*86400;
                h = round((s_temp/3600));
                if (h>s_temp/3600)
                    h = h-1; 
                end
                m =round(((s_temp/3600)-h)*60);
                if (m>((s_temp/3600)-h)*60)
                    m = m-1; 
                end
                s = s_temp-(h*3600)-m*60;
                if(d~=0)
                    app.TextoTiempogesto_2.Text = append("Duración: ",num2str(d)," Día, ",num2str(h)," h ",...
                                   num2str(m)," m ",num2str(s)," s");
                else
                    if(h~=0)
                        app.TextoTiempogesto_2.Text = append("Duración: ",num2str(h)," h ",...
                                   num2str(m)," m ",num2str(s)," s");
                    else
                        if(m~=0)
                            app.TextoTiempogesto_2.Text = append("Duración: ",num2str(m)," m ",num2str(s)," s");
                        else
                            app.TextoTiempogesto_2.Text = append("Duración: ",num2str(s)," s");
                        end
                    end
                end
                ylim(app.figFpz_3, [min(app.datosgesto_2) max(app.datosgesto_2)]);
                Epocas = size(app.datosgesto_2,2) ; %se toma el tamano del buffer obtenido
                L = Epocas; %longitud del vector epocas
                
                t=linspace(0, Epocas/1000, L);
                h1 = plot(app.figFpz_3, t, app.datosgesto_2, 'Color','b') %se plotea esa muestra
            end
            
               % Get a handle to the target tab group
            targetTabGroup = app.TabGroup3; % Replace with the actual name
            
            % Set the SelectedTab property to the desired tab
            targetTabGroup.SelectedTab = app.Gesto2Tab; % Replace with the actual tab name
        end

        % Value changed function: SeleccionartodoCheckBox_2
        function SeleccionartodoCheckBox_2ValueChanged(app, event)
            value = app.SeleccionartodoCheckBox_2.Value;
            if app.SeleccionartodoCheckBox_2.Value ==1

                app.TCDesviacinEstndarCheckBox.Value=1;
                app.TCEnergaAcumuladaCheckBox.Value=1;
                app.TCCurtosisCheckBox.Value=1;
                app.TCValorMedioAbsolutoMAVCheckBox.Value=1;
                app.TCCrucesporceroZCCheckBox.Value=1;

            end
            if app.SeleccionartodoCheckBox_2.Value ==0
                
                app.TCDesviacinEstndarCheckBox.Value=0;
                app.TCEnergaAcumuladaCheckBox.Value=0;
                app.TCCurtosisCheckBox.Value=0;
                app.TCValorMedioAbsolutoMAVCheckBox.Value=0;
                app.TCCrucesporceroZCCheckBox.Value=0;
            end
        end

        % Button pushed function: CrearPlantillaxlsxButton
        function CrearPlantillaxlsxButtonPushed(app, event)
               % Get the user's selection using a popup dialog
            userChoice = uiconfirm(app.UIFigure, ...
                'Elija el gesto', ...
                'Tipos de gestos:', ...
                'Options', {'gesto_1', 'gesto_2'}, ...
                'DefaultOption', 'gesto_1');
            
            % HARDCODED
            % Allow the user to interactively select a folder using a dialog
            templateFolderPath = uigetdir('C:\', 'Seleccione dónde desea guardar las señales');
            
            if templateFolderPath == 0
                % The user canceled the folder selection, handle this case as needed
                error('Usuario cancelo acción.');
            end
            
            
            % Set the template filename based on the user's choice
            if strcmp(userChoice, 'gesto_1')
                templateFilename = fullfile(templateFolderPath,'gesto_1_user.xlsx');
            else
                templateFilename = fullfile(templateFolderPath,'gesto_2_user.xlsx');
            end
            
            % AQUI SE PODRIA MANIPULAR LO QUE RECIBE EL BIOPAC
            % EN TIEMPO REAL
            
            
            % AQUI SE AGREGARIA AL TEMPLATECONTENT
            % Create a cell array with the content
            templateContent = {'data', 'data_length_sec','sampling_frequency','channels'};
            
            % Write the content to the Excel file
            xlswrite(templateFilename, templateContent);
            
            % Open the template
            winopen(templateFilename);
        end

        % Callback function
        function AbrirPlantillaxlsxButtonPushed(app, event)
               % Get the user's selection using a popup dialog
            userChoice = uiconfirm(app.UIFigure, ...
                'Elija el gesto', ...
                'Tipos de gestos:', ...
                'Options', {'Head Down', 'Blink'}, ...
                'DefaultOption', 'Fist Up');
            
            % HARDCODED
            templateFolderPath = 'C:\Users\fersa\Desktop\UVG\Sem2_1\Estudio-Epilepsia-2023\Pruebas\Interfaces biomedicas\Roberto Cáceres\Códigos\DatosBiopac\EEG_Signals\ExcelTemplates';

            % Set the template filename based on the user's choice
            if strcmp(userChoice, 'Head Down')
                templateFilename = fullfile(templateFolderPath,'headDown_user.xlsx');
            else
                templateFilename = fullfile(templateFolderPath,'blink_user.xlsx');
            end
            
            % AQUI SE PODRIA MANIPULAR LO QUE RECIBE EL BIOPAC
            % EN TIEMPO REAL
            
            
            % AQUI SE AGREGARIA AL TEMPLATECONTENT
            % Create a cell array with the content
            templateContent = {'data','alpha','beta','delta','theta', 'data_length_sec','sampling_frequency','channels'};
            
            % Write the content to the Excel file
            xlswrite(templateFilename, templateContent);
            
            % Open the template
            winopen(templateFilename);
        end

        % Button pushed function: IniciarExtraccinButton_2
        function IniciarExtraccinButton_2Pushed(app, event)
         % Mensaje de advertencia 
            message = sprintf('Esta operación puede tomar algunos minutos');
            % Mensaje de advertencia 
            message = sprintf('Esta operación puede tomar algunos minutos');
            %uialert(app.ClasificadorAnotacionesUIFigure,message,'Aviso',...
            %'Icon','warning');          
            Features_result = struct();
            Features_result.Car_gesto_1.VectorCaracteristicas_gesto_1 = [];
            Features_result.Car_gesto_2.VectorCaracteristicas_gesto_2 = [];
            Features_result.Car_gesto_1.data = [];
            Features_result.Car_gesto_2.data = [];
            app.Matriz_features_gesto_1 = 0;
            app.channel_ventana_gesto_1 = 0;
            app.Matriz_features_gesto_2 = 0;
            app.channel_ventana_gesto_2 = 0;
            
            
            app.c_gesto_1 = 1; 
            app.c_gesto_2 = 1;
            %Se elige si es analisis de TC
            app.op =[0,0,0,0,0,0];                              
            app.canales = 1; %Por el momento, sólo se usa 1 canal de cada señal                      
                               

            if (app.TC_Flag==1)   %Tiempo continuo            
                seleccion1 = '';seleccion2 = '';seleccion3 = '';seleccion4 = '';seleccion5 = '';
                if app.TCDesviacinEstndarCheckBox_2.Value
                    app.op(1) =1;
                    seleccion1= 'DesviaciónEstándar'; 
                end
                if app.TCCurtosisCheckBox_2.Value
                    app.op(2) =1;
                    seleccion2= 'Curtosis'; 
                end
                if app.TCCrucesporceroZCCheckBox_2.Value
                    app.op(3) =1;
                    seleccion3= 'CrucesPorCero'; 
                end
                if app.TCValorMedioAbsolutoMAVCheckBox_2.Value
                    app.op(4) =1;
                    seleccion4= 'ValorMedioAbsoluto'; 
                end
                if app.TCEnergaAcumuladaCheckBox_2.Value
                   app.op(5) =1;
                   seleccion5= 'EnergíaAcumulada'; 
                end
                
            else %Frecuencia
                selecc1='';selecc2='';selecc3='';selecc4='';selecc5='';selecc6='';
                if app.ratio_1CheckBox.Value % θ/β
                    app.op(1)=1;
                    selecc1 = 'Ratio1';
                end
                if app.ratio_2CheckBox.Value % β/α
                    app.op(2)=1;
                    selecc2 = 'Ratio2';
                end
                if app.ratio_3CheckBox.Value % (θ + α)/β
                    app.op(3)=1;
                    selecc3 = 'Ratio3';
                end
                if app.ratio_4CheckBox.Value % θ/α
                    app.op(4)=1;
                    selecc4 = 'Ratio4';
                end
                if app.ratio_5CheckBox.Value % (θ + α)/(α + β)
                    app.op(5)=1;
                    selecc5 = 'Ratio5';
                end
                if app.StdFreqCheckBox.Value % std
                    app.op(6)=1;
                    selecc6 = 'Std';
                end
            end


             % Variables de segmentacion
            lower_threshold = app.ThresholdInicialSpinner.Value;
            upper_threshold = app.ThresholdFinalSpinner.Value;
            min_segment_length = app.VentanaMnimaSpinner.Value;


            %% Segmentation
            [segments, app.emg_gesto_1, app.segment_start_gesto_1, app.segment_end_gesto_1]= SegmentV2(app.datosgesto_1, app.Fs_gesto_1, lower_threshold, upper_threshold, min_segment_length, 0);
            % Segmentation
            [segments_2, app.emg_gesto_2, app.segment_start_gesto_2, app.segment_end_gesto_2]= SegmentV2(app.datosgesto_2, app.Fs_gesto_1, lower_threshold, upper_threshold, min_segment_length, 0);
          
            
            if (app.TC_Flag==1)   %Tiempo continuo 
                i = 1;
                 while i <= length(app.segment_start_gesto_1)
                        % Extraer características utilizando FeaturesV2
                        [vector_gesto_1, ventanadatos_gesto_1, ~] = FeaturesV2023(app.emg_gesto_1(app.segment_start_gesto_1(i):app.segment_end_gesto_1(i)), app.canales, app.segment_end_gesto_1(i) - app.segment_start_gesto_1(i) + 1, app.c_gesto_1, app.op);
                        % Hacer algo con las características extraídas de gesto_1, por ejemplo, almacenarlas                
                        app.Matriz_features_gesto_1 = vector_gesto_1;
                        app.channel_ventana_gesto_1 = ventanadatos_gesto_1;
                        Features_result.Car_gesto_1.VectorCaracteristicas_gesto_1 = [Features_result.Car_gesto_1.VectorCaracteristicas_gesto_1; vector_gesto_1];                            
                        Features_result.Car_gesto_1.Caracteristicas = [seleccion1,',',seleccion2,',',seleccion3,',',seleccion4,',',seleccion5];
                        Features_result.Car_gesto_1.canales = app.c_gesto_1;
                        Features_result.Car_gesto_1.data = [Features_result.Car_gesto_1.data; ventanadatos_gesto_1];
                        Features_result.Car_gesto_1.FMuestreo = app.Fs_gesto_1;
                        Features_result.Car_gesto_1.Ventana =  app.segment_end_gesto_1 - app.segment_start_gesto_1 + 1;
                        Features_result.Car_gesto_1.Etiquetas_gesto_1 =  ones(length(Features_result.Car_gesto_1.VectorCaracteristicas_gesto_1),1);
                        % Move to the next segment
                        i = i + 1;
                end
                    
                i = 1;
                while i <= length(app.segment_start_gesto_2)
                    % Extraer características utilizando FeaturesV2 para gesto_2
                    [vector_gesto_2,ventanadatos_gesto_2, ~] = FeaturesV2023(app.emg_gesto_2(app.segment_start_gesto_2(i):app.segment_end_gesto_2(i)), app.canales, app.segment_end_gesto_2(i) - app.segment_start_gesto_2(i) + 1, app.c_gesto_2, app.op);
                   
                    % Hacer algo con las características extraídas de gesto_2, por ejemplo, almacenarlas
                    app.Matriz_features_gesto_2 = vector_gesto_2;
                    app.channel_ventana_gesto_2 = ventanadatos_gesto_2;
                    Features_result.Car_gesto_2.VectorCaracteristicas_gesto_2 = [Features_result.Car_gesto_2.VectorCaracteristicas_gesto_2; vector_gesto_2];
                    Features_result.Car_gesto_2.Caracteristicas = [seleccion1,',',seleccion2,',',seleccion3,',',seleccion4,',',seleccion5];
                    Features_result.Car_gesto_2.canales = app.c_gesto_2;
                    Features_result.Car_gesto_2.data = [Features_result.Car_gesto_2.data; ventanadatos_gesto_2];
                    Features_result.Car_gesto_2.FMuestreo = app.Fs_gesto_2;
                    Features_result.Car_gesto_2.Ventana =  app.segment_end_gesto_2 - app.segment_start_gesto_2 + 1;
                    Features_result.Car_gesto_2.Etiquetas_gesto_2 = 2*ones(length(Features_result.Car_gesto_2.VectorCaracteristicas_gesto_2),1);
                    % Move to the next segment
                    i = i + 1;
                end   
            else % FREQUENCY
                
                 i = 1;
                 while i <= length(app.segment_start_gesto_1)
                        % Extraer características utilizando FeaturesV2
                        [vector_gesto_1, ventanadatos_gesto_1, ~] = FeaturesV3023(app.emg_gesto_1(app.segment_start_gesto_1(i):app.segment_end_gesto_1(i)),app.Fs_gesto_1, app.canales, app.segment_end_gesto_1(i) - app.segment_start_gesto_1(i) + 1, app.c_gesto_1, app.op);
                        % Hacer algo con las características extraídas de gesto_1, por ejemplo, almacenarlas                
                        app.Matriz_features_gesto_1 = vector_gesto_1;
                        app.channel_ventana_gesto_1 = ventanadatos_gesto_1;
                        Features_result.Car_gesto_1.VectorCaracteristicas_gesto_1 = [Features_result.Car_gesto_1.VectorCaracteristicas_gesto_1; vector_gesto_1];                            
                    Features_result.Car_gesto_1.Caracteristicas = [selecc1,',',selecc2,',',selecc3,',',selecc4,',',selecc5,',',selecc6];
                        Features_result.Car_gesto_1.canales = app.c_gesto_1;
                        Features_result.Car_gesto_1.data = [Features_result.Car_gesto_1.data; ventanadatos_gesto_1];
                        Features_result.Car_gesto_1.FMuestreo = app.Fs_gesto_1;
                        Features_result.Car_gesto_1.Ventana =  app.segment_end_gesto_1 - app.segment_start_gesto_1 + 1;
                        Features_result.Car_gesto_1.Etiquetas_gesto_1 =  ones(length(Features_result.Car_gesto_1.VectorCaracteristicas_gesto_1),1);
                        % Move to the next segment
                        i = i + 1;
                end
                    
                i = 1;
                while i <= length(app.segment_start_gesto_2)
                    % Extraer características utilizando FeaturesV2 para gesto_2
                    [vector_gesto_2,ventanadatos_gesto_2, ~] = FeaturesV3023(app.emg_gesto_2(app.segment_start_gesto_2(i):app.segment_end_gesto_2(i)),app.Fs_gesto_2, app.canales, app.segment_end_gesto_2(i) - app.segment_start_gesto_2(i) + 1, app.c_gesto_2, app.op);
                   
                    % Hacer algo con las características extraídas de gesto_2, por ejemplo, almacenarlas
                    app.Matriz_features_gesto_2 = vector_gesto_2;
                    app.channel_ventana_gesto_2 = ventanadatos_gesto_2;
                    Features_result.Car_gesto_2.VectorCaracteristicas_gesto_2 = [Features_result.Car_gesto_2.VectorCaracteristicas_gesto_2; vector_gesto_2];
                    Features_result.Car_gesto_2.Caracteristicas = [selecc1,',',selecc2,',',selecc3,',',selecc4,',',selecc5,',',selecc6];
                    Features_result.Car_gesto_2.canales = app.c_gesto_2;
                    Features_result.Car_gesto_2.data = [Features_result.Car_gesto_2.data; ventanadatos_gesto_2];
                    Features_result.Car_gesto_2.FMuestreo = app.Fs_gesto_2;
                    Features_result.Car_gesto_2.Ventana =  app.segment_end_gesto_2 - app.segment_start_gesto_2 + 1;
                    Features_result.Car_gesto_2.Etiquetas_gesto_2 = 2*ones(length(Features_result.Car_gesto_2.VectorCaracteristicas_gesto_2),1);
                    % Move to the next segment
                    i = i + 1;
                end   
                
                disp('si funciona frecuencia')
            end
            Features_result.Tipo_Clasificacion = app.ClassType;
            Features_result.Vector_Caracteristicas = app.op;
            Features_result.Tipo_Caracteristicas = 0;
        
            %Guardar archivo MAT
            startingFolder = 'C:\Program Files\MATLAB'
            if ~exist(startingFolder, 'dir')
                startingFolder = pwd;
            end
            % Put in the name of the mat file that the user wants to save.
            % defaultFileName = fullfile(startingFolder, '*.mat')
            [file,path] = uiputfile('VectorFeatures.mat','Save file name');
            if file == 0
            % User clicked the Cancel button.
             return;
            end
            
            %AQUI ESTABA LO DEL STRUCT DE FEATURES ANTES
            
            %Guardar archivo en la ruta seleccionada
            fullFileName = fullfile(path, file);            
            save(fullFileName,'Features_result')
            
            %Mensaje de exito 
            message = sprintf(['Archivo ',file,' guardado exitosamente']);
            %uialert(app.ClasificadorAnotacionesUIFigure,message,'Aviso',...
            %'Icon','success');         


        end

        % Value changed function: SeleccionartodoCheckBox_4
        function SeleccionartodoCheckBox_4ValueChanged(app, event)
            value = app.SeleccionartodoCheckBox_4.Value;
            if app.SeleccionartodoCheckBox_4.Value ==1

                app.TCDesviacinEstndarCheckBox_2.Value=1;
                app.TCEnergaAcumuladaCheckBox_2.Value=1;
                app.TCCurtosisCheckBox_2.Value=1;
                app.TCValorMedioAbsolutoMAVCheckBox_2.Value=1;
                app.TCCrucesporceroZCCheckBox_2.Value=1;

            end
            if app.SeleccionartodoCheckBox_4.Value ==0
                
                app.TCDesviacinEstndarCheckBox_2.Value=0;
                app.TCEnergaAcumuladaCheckBox_2.Value=0;
                app.TCCurtosisCheckBox_2.Value=0;
                app.TCValorMedioAbsolutoMAVCheckBox_2.Value=0;
                app.TCCrucesporceroZCCheckBox_2.Value=0;
            end
        end

        % Selection change function: TabGroup4
        function TabGroup4SelectionChanged(app, event)
            
            %% ALTERNAR ENTRE OPCIONES DISPONIBLES EXTRACCION DE CARACTERISTICAS
            selectedTab = app.TabGroup4.SelectedTab; % Get the selected tab
            if selectedTab == app.TiempoContinuoTab;
                app.TC_Flag = 1;
                app.Fs_Flag = 0;
            else
                app.Fs_Flag = 1;    
                app.TC_Flag = 0;
            end
            
            
        end

        % Callback function
        function CargarMATEEG_HeadDownPushed(app, event)
            
        end

        % Callback function
        function CargarMATEEG_BlinkPushed(app, event)
   
        end

        % Value changed function: SeleccionartodoCheckBox_5
        function SeleccionartodoCheckBox_5ValueChanged(app, event)
            value = app.SeleccionartodoCheckBox_5.Value;
            if app.SeleccionartodoCheckBox_5.Value ==1

                app.ratio_5CheckBox.Value=1;
                app.ratio_4CheckBox.Value=1;
                app.ratio_3CheckBox.Value=1;
                app.ratio_2CheckBox.Value=1;
                app.ratio_1CheckBox.Value=1;
                app.StdFreqCheckBox.Value=1;

            end
            if app.SeleccionartodoCheckBox_5.Value ==0
                
                app.ratio_5CheckBox.Value=0;
                app.ratio_4CheckBox.Value=0;
                app.ratio_3CheckBox.Value=0;
                app.ratio_2CheckBox.Value=0;
                app.ratio_1CheckBox.Value=0;
                app.StdFreqCheckBox.Value=0;
            end
        end

        % Button pushed function: IniciarBSLAnalysisButton
        function IniciarBSLAnalysisButtonPushed(app, event)
                     % Specify the path to the folder containing the .exe file
    folderPath = 'C:\Program Files\BIOPAC Systems, Inc\BSL Analysis 4.1';
    
    % Specify the name of the .exe file (including the extension)
    exeName = 'BSL Analysis 4.1.exe';
    
    % Build the full path to the .exe file
    exePath = fullfile(folderPath, exeName);
    
    % Launch the .exe file
    system(exePath);
        end

        % Button pushed function: SegmentarButton, SegmentarButton_2
        function SegmentarButtonPushed(app, event)
            % Variables de segmentacion
            lower_threshold = app.ThresholdInicialSpinner.Value;
            upper_threshold = app.ThresholdFinalSpinner.Value;
            min_segment_length = app.VentanaMnimaSpinner.Value;
        
            %% Segmentation for gesto_1
            [segments_gesto_1, app.emg_gesto_1, app.segment_start_gesto_1, app.segment_end_gesto_1] = SegmentV2(app.datosgesto_1, app.Fs_gesto_1, lower_threshold, upper_threshold, min_segment_length, 1);
        
            %% Segmentation for gesto_2
            [segments_gesto_2, app.emg_gesto_2, app.segment_start_gesto_2, app.segment_end_gesto_2] = SegmentV2(app.datosgesto_2, app.Fs_gesto_2, lower_threshold, upper_threshold, min_segment_length, 1);
        
            % Display a success message
            message = 'Verifique o vuelva a segmentar';
            
        end

        % Button pushed function: ExtraermatsButton, 
        % ExtraermatsButton_2
        function ExtraermatsButtonPushed(app, event)
            % Display an alert message
            alertMessage = 'Este proceso puede tomar un momento...';
            h = msgbox(alertMessage, 'Alerta', 'warn');
            
            % Get the paths of the two .xlsx files using uigetfile dialog
            
            [file1, path1] = uigetfile('*.xlsx', 'Seleccione el primer archivo .xlsx');
            [file2, path2] = uigetfile('*.xlsx', 'Seleccione el segundo archivo .xlsx');
    
            % Check if the user canceled the file selection
            if isequal(file1, 0) || isequal(file2, 0)
                % Handle the case where the user canceled the file selection
                return; % Exit the function
            end
    
            % Create the full file paths
            filePath1 = fullfile(path1, file1);
            filePath2 = fullfile(path2, file2);
    
            % Call the autoextract_func_inter function with the file paths as arguments
            autoextract_func_inter(filePath1, filePath2);
        end

        % Button pushed function: ExtraersegmentosmatsButton, 
        % ExtraersegmentosmatsButton_2
        function GuardarsegmentosmatsButtonPushed(app, event)
            [file,path,~] = uigetfile({'*.mat'}, 'Selecciona el .mat del GESTO 1');
            %Si no se hace seleccion
            if isequal(file,0)
                 disp('Usuario canceló operación');
            else
                disp(['EL usuario selecccionó ', fullfile(path,file)]);
                %Asegurarse que el workspace cargado contenga la variable con
                %el nombre 'gesto_nuevo'
                workspace = load(strcat(path,file),'gesto_1');
                %Cargamos la señal EMG a la variable de datos respectiva
                gesto_1 = workspace.gesto_1 
            end
            
            [file, path] = uigetfile('*.mat', 'Selecciona el .mat del GESTO 2');
            % Check if the user canceled the file selection
            if file == 0
                return;  % User clicked the Cancel button.
            end
        
            % Load the MAT file
            fullFileName = fullfile(path, file);
            loadedData = load(fullFileName);
        
            % Check if the loaded data contains Clasificador_Entrenado
            if isfield(loadedData, 'gesto_2')
                % The loaded data contains a Clasificador_Entrenado struct
                gesto_2 = loadedData.gesto_2;
            else 
                disp('Usuario canceló operación');
            end
            
            % Display an alert message
            alertMessage = 'Este proceso puede tomar un momento...';
            h = msgbox(alertMessage, 'Alerta', 'warn');
            
            % Call the segment_auto_func function with the data as arguments
            [~, ~] = segment_auto_func(gesto_1, gesto_2);
            
            
        end

        % Button pushed function: CrearPlantillaxlsxButton_2
        function CrearPlantillaxlsxButton_2Pushed(app, event)
              % Get the user's selection using a popup dialog
            userChoice = uiconfirm(app.UIFigure, ...
                'Elija el gesto', ...
                'Tipos de gestos:', ...
                'Options', {'gesto_1', 'gesto_2'}, ...
                'DefaultOption', 'gesto_1');
            
            % HARDCODED
            % Allow the user to interactively select a folder using a dialog
            templateFolderPath = uigetdir('C:\', 'Seleccione dónde desea guardar las señales');
            
            if templateFolderPath == 0
                % The user canceled the folder selection, handle this case as needed
                error('Usuario cancelo acción.');
            end
            
            
            % Set the template filename based on the user's choice
            if strcmp(userChoice, 'gesto_1')
                templateFilename = fullfile(templateFolderPath,'gesto_1_user.xlsx');
            else
                templateFilename = fullfile(templateFolderPath,'gesto_2_user.xlsx');
            end
            
            % AQUI SE PODRIA MANIPULAR LO QUE RECIBE EL BIOPAC
            % EN TIEMPO REAL
            
            
            % AQUI SE AGREGARIA AL TEMPLATECONTENT
            % Create a cell array with the content
            templateContent = {'data','alpha','beta','delta','theta','data_length_sec','sampling_frequency','channels'};
            
            % Write the content to the Excel file
            xlswrite(templateFilename, templateContent);
            
            % Open the template
            winopen(templateFilename);
        end

        % Button pushed function: CargarMATgesto_1Button_2
        function CargarMATgesto_1Button_2Pushed(app, event)
            
            %Seleccionar archivo obtenido de base de datos
            [file,path,~] = uigetfile({'*.mat'}, 'Seleccionar archivo .mat');
            %Si no se hace seleccion
            if isequal(file,0)
                 disp('Usuario canceló operación');
            else
                disp(['EL usuario selecccionó ', fullfile(path,file)]);
                %Asegurarse que el workspace cargado contenga la variable con
                %el nombre 'gesto_1'
                workspace = load(strcat(path,file),'gesto_1');
                %Cargamos la señal EMG a la variable de datos respectiva
                app.datosgesto_1 = workspace.gesto_1.data;
                app.rawgesto_1 = workspace.gesto_1;
                %Cargamos la frecuencia de muestreo de los datos, se muestra en
                %el label por si se necesita modificar
                app.Fs_gesto_1 = workspace.gesto_1.sampling_frequency;
                app.FreqMuestreoFs_gesto_1EditField_2.Value = app.Fs_gesto_1;
                
                %Obtenemos el tamano del struct 
                datasize = size(app.datosgesto_1);
                %Se indica en el texto derecho el tamaño y duración de los datos a emplear
                app.TextoDimensionesgesto_3.Text = append("Tamaño: ",num2str(datasize(1))," x ",num2str(datasize(2)));
                segundos = workspace.gesto_1.data_length_sec ;                               
                d = round((segundos/86400));
                if (d>segundos/86400)
                    d = d-1; 
                end
                s_temp = segundos-d*86400;
                h = round((s_temp/3600));
                if (h>s_temp/3600)
                    h = h-1; 
                end
                m =round(((s_temp/3600)-h)*60);
                if (m>((s_temp/3600)-h)*60)
                    m = m-1; 
                end
                s = s_temp-(h*3600)-m*60;
                if(d~=0)
                    app.TextoTiempogesto_3.Text = append("Duración: ",num2str(d)," Día, ",num2str(h)," h ",...
                                   num2str(m)," m ",num2str(s)," s");
                else
                    if(h~=0)
                        app.TextoTiempogesto_3.Text = append("Duración: ",num2str(h)," h ",...
                                   num2str(m)," m ",num2str(s)," s");
                    else
                        if(m~=0)
                            app.TextoTiempogesto_3.Text = append("Duración: ",num2str(m)," m ",num2str(s)," s");
                        else
                            app.TextoTiempogesto_3.Text = append("Duración: ",num2str(s)," s");
                        end
                    end
                end
                ylim(app.figFpz_2, [min(app.datosgesto_1) max(app.datosgesto_1)]);
                Epocas = size(app.datosgesto_1,2) ; %se toma el tamano del buffer obtenido
                L = Epocas; %longitud del vector epocas
                
                t=linspace(0, Epocas/1000, L);
                h1 = plot(app.figFpz_2, t, app.datosgesto_1, 'Color','r') %se plotea esa muestra
            end
               % Get a handle to the target tab group
            targetTabGroup = app.TabGroup3; % Replace with the actual name
            
            % Set the SelectedTab property to the desired tab
            targetTabGroup.SelectedTab = app.Gesto1Tab; % Replace with the actual tab name
        end

        % Button pushed function: CargarMATgesto_2Button_2
        function CargarMATgesto_2Button_2Pushed(app, event)
            
            %Seleccionar archivo obtenido de base de datos
            [file,path,~] = uigetfile({'*.mat'}, 'Seleccionar archivo .mat');
            %Si no se hace seleccion
            if isequal(file,0)
                 disp('Usuario canceló operación');
            else
                disp(['EL usuario selecccionó ', fullfile(path,file)]);
                %Asegurarse que el workspace cargado contenga la variable con
                %el nombre 'gesto_2'
                workspace = load(strcat(path,file),'gesto_2');
                %Cargamos la señal EMG a la variable de datos respectiva
                app.datosgesto_2 = workspace.gesto_2.data;
                app.rawgesto_2 = workspace.gesto_2;
                %Cargamos la frecuencia de muestreo de los datos, se muestra en
                %el label por si se necesita modificar
                app.Fs_gesto_2 = workspace.gesto_2.sampling_frequency;
                app.FreqMuestreoFs_gesto_2EditField_2.Value = app.Fs_gesto_2;
                
                %Obtenemos el tamano del struct 
                datasize = size(app.datosgesto_2);
                %Se indica en el texto derecho el tamaño y duración de los datos a emplear
                app.TextoDimensionesgesto_4.Text = append("Tamaño: ",num2str(datasize(1))," x ",num2str(datasize(2)));
                segundos = workspace.gesto_2.data_length_sec ;                               
                d = round((segundos/86400));
                if (d>segundos/86400)
                    d = d-1; 
                end
                s_temp = segundos-d*86400;
                h = round((s_temp/3600));
                if (h>s_temp/3600)
                    h = h-1; 
                end
                m =round(((s_temp/3600)-h)*60);
                if (m>((s_temp/3600)-h)*60)
                    m = m-1; 
                end
                s = s_temp-(h*3600)-m*60;
                if(d~=0)
                    app.TextoTiempogesto_4.Text = append("Duración: ",num2str(d)," Día, ",num2str(h)," h ",...
                                   num2str(m)," m ",num2str(s)," s");
                else
                    if(h~=0)
                        app.TextoTiempogesto_4.Text = append("Duración: ",num2str(h)," h ",...
                                   num2str(m)," m ",num2str(s)," s");
                    else
                        if(m~=0)
                            app.TextoTiempogesto_4.Text = append("Duración: ",num2str(m)," m ",num2str(s)," s");
                        else
                            app.TextoTiempogesto_4.Text = append("Duración: ",num2str(s)," s");
                        end
                    end
                end
                ylim(app.figFpz_3, [min(app.datosgesto_2) max(app.datosgesto_2)]);
                Epocas = size(app.datosgesto_2,2) ; %se toma el tamano del buffer obtenido
                L = Epocas; %longitud del vector epocas
                
                t=linspace(0, Epocas/1000, L);
                h1 = plot(app.figFpz_3, t, app.datosgesto_2, 'Color','b') %se plotea esa muestra
            end
               % Get a handle to the target tab group
            targetTabGroup = app.TabGroup3; % Replace with the actual name
            
            % Set the SelectedTab property to the desired tab
            targetTabGroup.SelectedTab = app.Gesto2Tab; % Replace with the actual tab name
        end

        % Button pushed function: RegresarButton
        function RegresarButtonPushed2(app, event)
            Interfaz_Inicio
            delete(app)
        end

        % Button down function: BiopacEMGTab
        function BiopacEMGTabButtonDown(app, event)

            % Get a handle to the target tab group
            targetTabGroup = app.TabGroup2; % Replace with the actual name
            
            % Set the SelectedTab property to the desired tab
            targetTabGroup.SelectedTab = app.BiopacSignalTab; % Replace with the actual tab name
   

        end

        % Button pushed function: SegmentarmatButton_2
        function SegmentarmatButton_2Pushed(app, event)
            % Variables de segmentacion
            lower_threshold = app.ThresholdInicialSpinner.Value;
            upper_threshold = app.ThresholdFinalSpinner.Value;
            min_segment_length = app.VentanaMnimaSpinner.Value;
            
            %Seleccionar archivo obtenido de base de datos
            [file,path,~] = uigetfile({'*.mat'}, 'Seleccionar archivo para segmentar.mat');
            
            %Si no se hace seleccion
            if isequal(file,0)
                disp('Usuario canceló operación');
            else
                disp(['EL usuario selecccionó ', fullfile(path,file)]);
                
                % Load the MAT file
                workspace = load(fullfile(path, file));
                
                % Check if 'gesto_1' data is available
                if isfield(workspace, 'gesto_1')
                    %Cargamos la señal EMG 'gesto_1' a la variable de datos respectiva
                    gesto = workspace.gesto_1.data;
                    %Cargamos la frecuencia de muestreo de los datos
                    Fs_gesto = workspace.gesto_1.sampling_frequency;
                    %% Segmentation for gesto_1
            [~, ~, ~ , ~] = SegmentV2(gesto, Fs_gesto, lower_threshold, upper_threshold, min_segment_length, 1);
                    
                end
                
                % Check if 'gesto_2' data is available
                if isfield(workspace, 'gesto_2')
                    %Cargamos la señal EMG 'gesto_2' to another variable if needed
                    gesto = workspace.gesto_2.data;
                    %Cargamos the frequency if needed
                    Fs_gesto = workspace.gesto_2.sampling_frequency;
                    %% Segmentation for gesto_1
            [~, ~, ~ , ~] = SegmentV2(gesto, Fs_gesto, lower_threshold, upper_threshold, min_segment_length, 1);
                end
            end


            % Display a success message
            message = 'Verifique o vuelva a segmentar';
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.RecoleccindeDatosEMGEEGUVGLabel = uilabel(app.Panel);
            app.RecoleccindeDatosEMGEEGUVGLabel.Text = 'Recolección de Datos  EMG/EEG - UVG';
            app.DesconectadoLampLabel = uilabel(app.Panel);
            app.DesconectadoLampLabel.Enable = 'off';
            app.DesconectadoLampLabel.Text = 'Desconectado';
            app.DesconectadoLamp = uilamp(app.Panel);
            app.DesconectadoLamp.Enable = 'off';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InstruccionesTab = uitab(app.TabGroup);
            app.InstruccionesTab.Title = 'Instrucciones';
            app.Label = uilabel(app.InstruccionesTab);
            app.Label.Text = '1. Se tienen que seleccionar las caracteristicas que se quieren incluir en los vectores de caracteristicas. ';
            app.Label_2 = uilabel(app.InstruccionesTab);
            app.Label_2.Text = '2. Al tener las características seleccionadas se debe crear la tabla donde se guardaran estos vectores. ';
            app.InstruccionesparaelusodelaInterfazLabel = uilabel(app.InstruccionesTab);
            app.InstruccionesparaelusodelaInterfazLabel.Text = 'Instrucciones para el uso de la Interfaz';
            app.Label_3 = uilabel(app.InstruccionesTab);
            app.Label_3.Text = '5. Se debe de conectar al Bitalino y luego empezar a recolectar datos empezando el streaming.  ';
            app.Label_4 = uilabel(app.InstruccionesTab);
            app.Label_4.Text = '3. Se debe de seleccionar la ventana de tiempo de las muestras que se tomarán.';
            app.Label_5 = uilabel(app.InstruccionesTab);
            app.Label_5.Text = '6. Para parar de recolectar data, se debe de apagar el streaming. ';
            app.Label_6 = uilabel(app.InstruccionesTab);
            app.Label_6.Text = '4. Se debe de seleccionar que clase se grabará. ';
            app.Label_7 = uilabel(app.InstruccionesTab);
            app.Label_7.Text = '7. Guardar la tabla, se recomienda colocar el nombre que identifique la clase y datos sobre el ambiente. ';
            app.BitalinoTab = uitab(app.TabGroup);
            app.BitalinoTab.Title = 'Bitalino';
            app.GrabacinPanel = uipanel(app.BitalinoTab);
            app.GrabacinPanel.Title = 'Grabación ';
            app.GuardarArchivoButton = uibutton(app.GrabacinPanel, 'push');
            app.GuardarArchivoButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarArchivoButtonPushed, true);
            app.GuardarArchivoButton.Text = 'Guardar Archivo';
            app.TiempodemuestraatomarEditFieldLabel = uilabel(app.GrabacinPanel);
            app.TiempodemuestraatomarEditFieldLabel.Text = 'Tiempo de muestra a tomar';
            app.TiempodemuestraatomarEditField = uieditfield(app.GrabacinPanel, 'numeric');
            app.TiempodemuestraatomarEditField.Limits = [0 25];
            app.TiempodemuestraatomarEditField.RoundFractionalValues = 'on';
            app.TiempodemuestraatomarEditField.Value = 5;
            app.NombreDelArchivoEditFieldLabel = uilabel(app.GrabacinPanel);
            app.NombreDelArchivoEditFieldLabel.Text = 'Nombre Del Archivo';
            app.NombreDelArchivoEditField = uieditfield(app.GrabacinPanel, 'text');
            app.NombreDelArchivoEditField.Value = 'Sesion_n_';
            app.ClaseagrabarDropDownLabel = uilabel(app.GrabacinPanel);
            app.ClaseagrabarDropDownLabel.Text = 'Clase a grabar';
            app.ClaseagrabarDropDown = uidropdown(app.GrabacinPanel);
            app.ClaseagrabarDropDown.Items = {'Wrist-Up', 'Wrist-Down', ''};
            app.ClaseagrabarDropDown.ValueChangedFcn = createCallbackFcn(app, @ClaseagrabarDropDownValueChanged, true);
            app.ClaseagrabarDropDown.Value = 'Wrist-Up';
            app.Image = uiimage(app.GrabacinPanel);
            app.Image.ImageSource = 'WristUp.jpg';
            app.IniciarsesinButton = uibutton(app.GrabacinPanel, 'push');
            app.IniciarsesinButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarsesinButtonPushed, true);
            app.IniciarsesinButton.Text = 'Iniciar sesión';
            app.NmerodemuestrastomadasLabel = uilabel(app.GrabacinPanel);
            app.NmerodemuestrastomadasLabel.Text = 'Número de muestras tomadas:';
            app.Label_9 = uilabel(app.GrabacinPanel);
            app.Label_9.Text = '0';
            app.Button = uibutton(app.GrabacinPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'BotonPausa.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.GrabacinPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'play.png';
            app.Button_2.Text = '';
            app.Lamp_4 = uilamp(app.GrabacinPanel);
            app.TerminarsesinButton = uibutton(app.GrabacinPanel, 'push');
            app.TerminarsesinButton.ButtonPushedFcn = createCallbackFcn(app, @TerminarsesinButtonPushed, true);
            app.TerminarsesinButton.Text = 'Terminar sesión';
            app.CaracteristicasSeleccionadasPanel = uipanel(app.BitalinoTab);
            app.CaracteristicasSeleccionadasPanel.Title = 'Caracteristicas Seleccionadas ';
            app.ZeroCrossingCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.ZeroCrossingCheckBox.Text = 'Zero Crossing';
            app.MAVCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.MAVCheckBox.Text = 'MAV';
            app.KurtosisCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.KurtosisCheckBox.Text = 'Kurtosis';
            app.PromediodeEnergaCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.PromediodeEnergaCheckBox.Text = 'Promedio de Energía';
            app.VarianzaCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.VarianzaCheckBox.Text = 'Varianza';
            app.AmplituddeWilsonCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.AmplituddeWilsonCheckBox.Text = 'Amplitud de Wilson';
            app.MediaCuadraticaCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.MediaCuadraticaCheckBox.Text = 'Media Cuadratica ';
            app.CrearTablaButton = uibutton(app.CaracteristicasSeleccionadasPanel, 'push');
            app.CrearTablaButton.ButtonPushedFcn = createCallbackFcn(app, @CrearTablaButtonPushed, true);
            app.CrearTablaButton.Text = 'Crear Tabla';
            app.ConectarBitalinoSwitchLabel = uilabel(app.CaracteristicasSeleccionadasPanel);
            app.ConectarBitalinoSwitchLabel.Text = 'Conectar Bitalino';
            app.ConectarBitalinoSwitch = uiswitch(app.CaracteristicasSeleccionadasPanel, 'slider');
            app.ConectarBitalinoSwitch.ValueChangedFcn = createCallbackFcn(app, @ConectarBitalinoSwitchValueChanged, true);
            app.EmpezarStreamingSwitchLabel = uilabel(app.CaracteristicasSeleccionadasPanel);
            app.EmpezarStreamingSwitchLabel.Text = 'Empezar Streaming';
            app.EmpezarStreamingSwitch = uiswitch(app.CaracteristicasSeleccionadasPanel, 'slider');
            app.EmpezarStreamingSwitch.ValueChangedFcn = createCallbackFcn(app, @EmpezarStreamingSwitchValueChanged, true);
            app.NombreBluetoothBitalinoEditFieldLabel = uilabel(app.CaracteristicasSeleccionadasPanel);
            app.NombreBluetoothBitalinoEditFieldLabel.Text = 'Nombre Bluetooth Bitalino';
            app.NombreBluetoothBitalinoEditField = uieditfield(app.CaracteristicasSeleccionadasPanel, 'text');
            app.NombreBluetoothBitalinoEditField.Value = 'BITalino-00-97';
            app.Lamp_2 = uilamp(app.CaracteristicasSeleccionadasPanel);
            app.Lamp_3 = uilamp(app.CaracteristicasSeleccionadasPanel);
            app.SeleccionartodoCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.SeleccionartodoCheckBox.ValueChangedFcn = createCallbackFcn(app, @SeleccionartodoCheckBoxValueChanged, true);
            app.SeleccionartodoCheckBox.Text = 'Seleccionar todo';
            app.BiopacEMGTab = uitab(app.TabGroup);
            app.BiopacEMGTab.Title = 'Biopac EMG';
            app.BiopacEMGTab.ButtonDownFcn = createCallbackFcn(app, @BiopacEMGTabButtonDown, true);
            app.SeleccindeCaractersticasaExtraerPanel_2 = uipanel(app.BiopacEMGTab);
            app.SeleccindeCaractersticasaExtraerPanel_2.Title = 'Selección de Características a Extraer';
            app.TCCrucesporceroZCCheckBox = uicheckbox(app.SeleccindeCaractersticasaExtraerPanel_2);
            app.TCCrucesporceroZCCheckBox.Text = 'Zero Crossing (ZC)';
            app.TCValorMedioAbsolutoMAVCheckBox = uicheckbox(app.SeleccindeCaractersticasaExtraerPanel_2);
            app.TCValorMedioAbsolutoMAVCheckBox.Text = 'Valor Medio Absoluto MAV';
            app.TCCurtosisCheckBox = uicheckbox(app.SeleccindeCaractersticasaExtraerPanel_2);
            app.TCCurtosisCheckBox.Text = 'Curtosis';
            app.TCEnergaAcumuladaCheckBox = uicheckbox(app.SeleccindeCaractersticasaExtraerPanel_2);
            app.TCEnergaAcumuladaCheckBox.Text = 'Energía Acumulada';
            app.TCDesviacinEstndarCheckBox = uicheckbox(app.SeleccindeCaractersticasaExtraerPanel_2);
            app.TCDesviacinEstndarCheckBox.Text = 'Desviación Estándar';
            app.SeleccionartodoCheckBox_2 = uicheckbox(app.SeleccindeCaractersticasaExtraerPanel_2);
            app.SeleccionartodoCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @SeleccionartodoCheckBox_2ValueChanged, true);
            app.SeleccionartodoCheckBox_2.Text = 'Seleccionar todo';
            app.IniciarExtraccinButton = uibutton(app.SeleccindeCaractersticasaExtraerPanel_2, 'push');
            app.IniciarExtraccinButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarExtraccinButtonPushed, true);
            app.IniciarExtraccinButton.Text = 'Iniciar Extracción';
            app.PaneldeControlSegmentacion = uipanel(app.SeleccindeCaractersticasaExtraerPanel_2);
            app.PaneldeControlSegmentacion.Title = 'Panel de Control - Segmentacion';
            app.ThresholdFinalSpinnerLabel = uilabel(app.PaneldeControlSegmentacion);
            app.ThresholdFinalSpinnerLabel.Text = 'Threshold Final';
            app.ThresholdFinalSpinner = uispinner(app.PaneldeControlSegmentacion);
            app.ThresholdFinalSpinner.Step = 0.0001;
            app.ThresholdFinalSpinner.Limits = [0 0.1];
            app.ThresholdFinalSpinner.Value = 0.002;
            app.ThresholdInicialSpinnerLabel = uilabel(app.PaneldeControlSegmentacion);
            app.ThresholdInicialSpinnerLabel.Text = 'Threshold Inicial';
            app.ThresholdInicialSpinner = uispinner(app.PaneldeControlSegmentacion);
            app.ThresholdInicialSpinner.Step = 0.0001;
            app.ThresholdInicialSpinner.Limits = [0 0.1];
            app.ThresholdInicialSpinner.Value = 0.0002;
            app.VentanaMnimaSpinnerLabel = uilabel(app.PaneldeControlSegmentacion);
            app.VentanaMnimaSpinnerLabel.Text = 'Ventana Mínima';
            app.VentanaMnimaSpinner = uispinner(app.PaneldeControlSegmentacion);
            app.VentanaMnimaSpinner.Step = 10;
            app.VentanaMnimaSpinner.Limits = [0 500];
            app.VentanaMnimaSpinner.Value = 100;
            app.SegmentarButton = uibutton(app.PaneldeControlSegmentacion, 'push');
            app.SegmentarButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentarButtonPushed, true);
            app.SegmentarButton.Text = 'Segmentar';
            app.ExtraersegmentosmatsButton_2 = uibutton(app.PaneldeControlSegmentacion, 'push');
            app.ExtraersegmentosmatsButton_2.ButtonPushedFcn = createCallbackFcn(app, @GuardarsegmentosmatsButtonPushed, true);
            app.ExtraersegmentosmatsButton_2.Text = 'Extraer segmentos .mat(s)';
            app.SegmentarmatButton_2 = uibutton(app.PaneldeControlSegmentacion, 'push');
            app.SegmentarmatButton_2.ButtonPushedFcn = createCallbackFcn(app, @SegmentarmatButton_2Pushed, true);
            app.SegmentarmatButton_2.Text = 'Segmentar .mat';
            app.CapturarSealesBiopacPanel = uipanel(app.BiopacEMGTab);
            app.CapturarSealesBiopacPanel.Title = 'Capturar Señales Biopac';
            app.CargarMATgesto_1Button = uibutton(app.CapturarSealesBiopacPanel, 'push');
            app.CargarMATgesto_1Button.ButtonPushedFcn = createCallbackFcn(app, @CargarMATgesto_1ButtonPushed, true);
            app.CargarMATgesto_1Button.Text = 'Cargat .mat';
            app.FreqMuestreoFsLabel = uilabel(app.CapturarSealesBiopacPanel);
            app.FreqMuestreoFsLabel.Text = 'Freq. Muestreo (Fs):';
            app.FreqMuestreoFs_gesto_1EditField = uieditfield(app.CapturarSealesBiopacPanel, 'numeric');
            app.HzLabel = uilabel(app.CapturarSealesBiopacPanel);
            app.HzLabel.Text = 'Hz';
            app.TextoDimensionesgesto_1 = uilabel(app.CapturarSealesBiopacPanel);
            app.TextoDimensionesgesto_1.Text = 'Tamaño: -';
            app.TextoTiempogesto_1 = uilabel(app.CapturarSealesBiopacPanel);
            app.TextoTiempogesto_1.Text = 'Duración: -';
            app.Gesto1EMGLabel = uilabel(app.CapturarSealesBiopacPanel);
            app.Gesto1EMGLabel.Text = 'Gesto 1 - EMG';
            app.Gesto2EMGLabel = uilabel(app.CapturarSealesBiopacPanel);
            app.Gesto2EMGLabel.Text = 'Gesto 2 - EMG';
            app.CargarMATgesto_2Button = uibutton(app.CapturarSealesBiopacPanel, 'push');
            app.CargarMATgesto_2Button.ButtonPushedFcn = createCallbackFcn(app, @CargarMATgesto_2ButtonPushed, true);
            app.CargarMATgesto_2Button.Text = 'Cargat .mat';
            app.HzLabel_2 = uilabel(app.CapturarSealesBiopacPanel);
            app.HzLabel_2.Text = 'Hz';
            app.FreqMuestreoFsLabel_2 = uilabel(app.CapturarSealesBiopacPanel);
            app.FreqMuestreoFsLabel_2.Text = 'Freq. Muestreo (Fs):';
            app.FreqMuestreoFs_gesto_2EditField = uieditfield(app.CapturarSealesBiopacPanel, 'numeric');
            app.TextoDimensionesgesto_2 = uilabel(app.CapturarSealesBiopacPanel);
            app.TextoDimensionesgesto_2.Text = 'Tamaño: -';
            app.TextoTiempogesto_2 = uilabel(app.CapturarSealesBiopacPanel);
            app.TextoTiempogesto_2.Text = 'Duración: -';
            app.TabGroup5 = uitabgroup(app.CapturarSealesBiopacPanel);
            app.GrabarGuardarSealTab = uitab(app.TabGroup5);
            app.GrabarGuardarSealTab.Title = 'Grabar/Guardar Señal';
            app.CrearPlantillaxlsxButton = uibutton(app.GrabarGuardarSealTab, 'push');
            app.CrearPlantillaxlsxButton.ButtonPushedFcn = createCallbackFcn(app, @CrearPlantillaxlsxButtonPushed, true);
            app.CrearPlantillaxlsxButton.Text = 'Crear Plantilla.xlsx';
            app.IniciarBSLAnalysisButton = uibutton(app.GrabarGuardarSealTab, 'push');
            app.IniciarBSLAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarBSLAnalysisButtonPushed, true);
            app.IniciarBSLAnalysisButton.Text = 'Iniciar BSL Analysis';
            app.CapturadesealesconBiopacygenerarxlsxLabel = uilabel(app.GrabarGuardarSealTab);
            app.CapturadesealesconBiopacygenerarxlsxLabel.Text = 'Captura de señales con Biopac y generar .xlsx';
            app.GenerarmatTab = uitab(app.TabGroup5);
            app.GenerarmatTab.Title = 'Generar .mat';
            app.ExtraermatsButton = uibutton(app.GenerarmatTab, 'push');
            app.ExtraermatsButton.ButtonPushedFcn = createCallbackFcn(app, @ExtraermatsButtonPushed, true);
            app.ExtraermatsButton.Text = 'Extraer .mat(s)';
            app.SeleccionarplantillasxlsxyextraerLabel = uilabel(app.GenerarmatTab);
            app.SeleccionarplantillasxlsxyextraerLabel.Text = 'Seleccionar plantillas .xlsx y extraer';
            app.BiopacEEGTab = uitab(app.TabGroup);
            app.BiopacEEGTab.Title = 'Biopac EEG';
            app.CapturarSealesBiopacPanel_2 = uipanel(app.BiopacEEGTab);
            app.CapturarSealesBiopacPanel_2.Title = 'Capturar Señales Biopac';
            app.TabGroup5_2 = uitabgroup(app.CapturarSealesBiopacPanel_2);
            app.GrabarGuardarSealTab_2 = uitab(app.TabGroup5_2);
            app.GrabarGuardarSealTab_2.Title = 'Grabar/Guardar Señal';
            app.CrearPlantillaxlsxButton_2 = uibutton(app.GrabarGuardarSealTab_2, 'push');
            app.CrearPlantillaxlsxButton_2.ButtonPushedFcn = createCallbackFcn(app, @CrearPlantillaxlsxButton_2Pushed, true);
            app.CrearPlantillaxlsxButton_2.Text = 'Crear Plantilla.xlsx';
            app.IniciarBSLAnalysisButton_2 = uibutton(app.GrabarGuardarSealTab_2, 'push');
            app.IniciarBSLAnalysisButton_2.Text = 'Iniciar BSL Analysis';
            app.CapturadesealesconBiopacygenerarxlsxLabel_2 = uilabel(app.GrabarGuardarSealTab_2);
            app.CapturadesealesconBiopacygenerarxlsxLabel_2.Text = 'Captura de señales con Biopac y generar .xlsx';
            app.GenerarmatTab_2 = uitab(app.TabGroup5_2);
            app.GenerarmatTab_2.Title = 'Generar .mat';
            app.ExtraermatsButton_2 = uibutton(app.GenerarmatTab_2, 'push');
            app.ExtraermatsButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExtraermatsButtonPushed, true);
            app.ExtraermatsButton_2.Text = 'Extraer .mat(s)';
            app.SeleccionarplantillasxlsxyextraerLabel_2 = uilabel(app.GenerarmatTab_2);
            app.SeleccionarplantillasxlsxyextraerLabel_2.Text = 'Seleccionar plantillas .xlsx y extraer';
            app.HzLabel_3 = uilabel(app.CapturarSealesBiopacPanel_2);
            app.HzLabel_3.Text = 'Hz';
            app.TextoDimensionesgesto_3 = uilabel(app.CapturarSealesBiopacPanel_2);
            app.TextoDimensionesgesto_3.Text = 'Tamaño: -';
            app.TextoTiempogesto_3 = uilabel(app.CapturarSealesBiopacPanel_2);
            app.TextoTiempogesto_3.Text = 'Duración: -';
            app.Gesto1EMGLabel_2 = uilabel(app.CapturarSealesBiopacPanel_2);
            app.Gesto1EMGLabel_2.Text = 'Gesto 1 - EMG';
            app.Gesto2EMGLabel_2 = uilabel(app.CapturarSealesBiopacPanel_2);
            app.Gesto2EMGLabel_2.Text = 'Gesto 2 - EMG';
            app.HzLabel_4 = uilabel(app.CapturarSealesBiopacPanel_2);
            app.HzLabel_4.Text = 'Hz';
            app.TextoDimensionesgesto_4 = uilabel(app.CapturarSealesBiopacPanel_2);
            app.TextoDimensionesgesto_4.Text = 'Tamaño: -';
            app.TextoTiempogesto_4 = uilabel(app.CapturarSealesBiopacPanel_2);
            app.TextoTiempogesto_4.Text = 'Duración: -';
            app.FreqMuestreoFsLabel_3 = uilabel(app.CapturarSealesBiopacPanel_2);
            app.FreqMuestreoFsLabel_3.Text = 'Freq. Muestreo (Fs):';
            app.FreqMuestreoFs_gesto_1EditField_2 = uieditfield(app.CapturarSealesBiopacPanel_2, 'numeric');
            app.FreqMuestreoFsLabel_4 = uilabel(app.CapturarSealesBiopacPanel_2);
            app.FreqMuestreoFsLabel_4.Text = 'Freq. Muestreo (Fs):';
            app.FreqMuestreoFs_gesto_2EditField_2 = uieditfield(app.CapturarSealesBiopacPanel_2, 'numeric');
            app.CargarMATgesto_1Button_2 = uibutton(app.CapturarSealesBiopacPanel_2, 'push');
            app.CargarMATgesto_1Button_2.ButtonPushedFcn = createCallbackFcn(app, @CargarMATgesto_1Button_2Pushed, true);
            app.CargarMATgesto_1Button_2.Text = 'Cargat .mat';
            app.CargarMATgesto_2Button_2 = uibutton(app.CapturarSealesBiopacPanel_2, 'push');
            app.CargarMATgesto_2Button_2.ButtonPushedFcn = createCallbackFcn(app, @CargarMATgesto_2Button_2Pushed, true);
            app.CargarMATgesto_2Button_2.Text = 'Cargat .mat';
            app.SeleccindeCaractersticasaExtraerPanel = uipanel(app.BiopacEEGTab);
            app.SeleccindeCaractersticasaExtraerPanel.Title = 'Selección de Características a Extraer';
            app.TabGroup4 = uitabgroup(app.SeleccindeCaractersticasaExtraerPanel);
            app.TabGroup4.SelectionChangedFcn = createCallbackFcn(app, @TabGroup4SelectionChanged, true);
            app.TiempoContinuoTab = uitab(app.TabGroup4);
            app.TiempoContinuoTab.Title = 'Tiempo Continuo';
            app.SeleccionartodoCheckBox_4 = uicheckbox(app.TiempoContinuoTab);
            app.SeleccionartodoCheckBox_4.ValueChangedFcn = createCallbackFcn(app, @SeleccionartodoCheckBox_4ValueChanged, true);
            app.SeleccionartodoCheckBox_4.Text = 'Seleccionar todo';
            app.TCCrucesporceroZCCheckBox_2 = uicheckbox(app.TiempoContinuoTab);
            app.TCCrucesporceroZCCheckBox_2.Text = 'Zero Crossing (ZC)';
            app.TCValorMedioAbsolutoMAVCheckBox_2 = uicheckbox(app.TiempoContinuoTab);
            app.TCValorMedioAbsolutoMAVCheckBox_2.Text = 'Valor Medio Absoluto MAV';
            app.TCCurtosisCheckBox_2 = uicheckbox(app.TiempoContinuoTab);
            app.TCCurtosisCheckBox_2.Text = 'Curtosis';
            app.TCEnergaAcumuladaCheckBox_2 = uicheckbox(app.TiempoContinuoTab);
            app.TCEnergaAcumuladaCheckBox_2.Text = 'Energía Acumulada';
            app.TCDesviacinEstndarCheckBox_2 = uicheckbox(app.TiempoContinuoTab);
            app.TCDesviacinEstndarCheckBox_2.Text = 'Desviación Estándar';
            app.FrecuenciaTab = uitab(app.TabGroup4);
            app.FrecuenciaTab.Title = 'Frecuencia';
            app.ratio_4CheckBox = uicheckbox(app.FrecuenciaTab);
            app.ratio_4CheckBox.Text = 'θ/α';
            app.ratio_2CheckBox = uicheckbox(app.FrecuenciaTab);
            app.ratio_2CheckBox.Text = 'β/α';
            app.ratio_1CheckBox = uicheckbox(app.FrecuenciaTab);
            app.ratio_1CheckBox.Text = 'θ/β';
            app.ratio_3CheckBox = uicheckbox(app.FrecuenciaTab);
            app.ratio_3CheckBox.Text = '(θ + α)/β';
            app.ratio_5CheckBox = uicheckbox(app.FrecuenciaTab);
            app.ratio_5CheckBox.Text = '(θ + α)/(α + β)';
            app.StdFreqCheckBox = uicheckbox(app.FrecuenciaTab);
            app.StdFreqCheckBox.Text = 'Desviación Estándar';
            app.SeleccionartodoCheckBox_5 = uicheckbox(app.FrecuenciaTab);
            app.SeleccionartodoCheckBox_5.ValueChangedFcn = createCallbackFcn(app, @SeleccionartodoCheckBox_5ValueChanged, true);
            app.SeleccionartodoCheckBox_5.Text = 'Seleccionar todo';
            app.PaneldeControlSegmentacion_2 = uipanel(app.SeleccindeCaractersticasaExtraerPanel);
            app.PaneldeControlSegmentacion_2.Title = 'Panel de Control - Segmentacion';
            app.ThresholdFinalSpinner_2Label = uilabel(app.PaneldeControlSegmentacion_2);
            app.ThresholdFinalSpinner_2Label.Text = 'Threshold Final';
            app.ThresholdFinalSpinner_2 = uispinner(app.PaneldeControlSegmentacion_2);
            app.ThresholdFinalSpinner_2.Step = 0.0001;
            app.ThresholdFinalSpinner_2.Limits = [0 0.1];
            app.ThresholdFinalSpinner_2.Value = 0.002;
            app.ThresholdInicialSpinner_2Label = uilabel(app.PaneldeControlSegmentacion_2);
            app.ThresholdInicialSpinner_2Label.Text = 'Threshold Inicial';
            app.ThresholdInicialSpinner_2 = uispinner(app.PaneldeControlSegmentacion_2);
            app.ThresholdInicialSpinner_2.Step = 0.0001;
            app.ThresholdInicialSpinner_2.Limits = [0 0.1];
            app.ThresholdInicialSpinner_2.Value = 0.0002;
            app.VentanaMnimaSpinner_2Label = uilabel(app.PaneldeControlSegmentacion_2);
            app.VentanaMnimaSpinner_2Label.Text = 'Ventana Mínima';
            app.VentanaMnimaSpinner_2 = uispinner(app.PaneldeControlSegmentacion_2);
            app.VentanaMnimaSpinner_2.Step = 10;
            app.VentanaMnimaSpinner_2.Limits = [0 500];
            app.VentanaMnimaSpinner_2.Value = 100;
            app.SegmentarButton_2 = uibutton(app.PaneldeControlSegmentacion_2, 'push');
            app.SegmentarButton_2.ButtonPushedFcn = createCallbackFcn(app, @SegmentarButtonPushed, true);
            app.SegmentarButton_2.Text = 'Segmentar';
            app.ExtraersegmentosmatsButton = uibutton(app.PaneldeControlSegmentacion_2, 'push');
            app.ExtraersegmentosmatsButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarsegmentosmatsButtonPushed, true);
            app.ExtraersegmentosmatsButton.Text = 'Extraer segmentos .mat(s)';
            app.SegmentarmatButton = uibutton(app.PaneldeControlSegmentacion_2, 'push');
            app.SegmentarmatButton.Text = 'Segmentar .mat';
            app.IniciarExtraccinButton_2 = uibutton(app.SeleccindeCaractersticasaExtraerPanel, 'push');
            app.IniciarExtraccinButton_2.ButtonPushedFcn = createCallbackFcn(app, @IniciarExtraccinButton_2Pushed, true);
            app.IniciarExtraccinButton_2.Text = 'Iniciar Extracción';
            app.LarecoleccinestenPausaLabel = uilabel(app.UIFigure);
            app.LarecoleccinestenPausaLabel.Visible = 'off';
            app.LarecoleccinestenPausaLabel.Text = 'La recolección está en Pausa';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.BitalinoTab_2 = uitab(app.TabGroup2);
            app.BitalinoTab_2.Title = 'Bitalino';
            app.figFpz = uiaxes(app.BitalinoTab_2);
            title(app.figFpz, 'Muestra Tomada')
            xlabel(app.figFpz, 't (s)')
            ylabel(app.figFpz, 'V')
            app.BiopacSignalTab = uitab(app.TabGroup2);
            app.BiopacSignalTab.Title = 'Biopac ~ Signal';
            app.TabGroup3 = uitabgroup(app.BiopacSignalTab);
            app.Gesto1Tab = uitab(app.TabGroup3);
            app.Gesto1Tab.Title = 'Gesto 1';
            app.figFpz_2 = uiaxes(app.Gesto1Tab);
            title(app.figFpz_2, 'Gesto 1')
            xlabel(app.figFpz_2, 't (s)')
            ylabel(app.figFpz_2, 'V')
            app.Canal1Label_2 = uilabel(app.Gesto1Tab);
            app.Canal1Label_2.Text = 'Canal 1';
            app.Gesto2Tab = uitab(app.TabGroup3);
            app.Gesto2Tab.Title = 'Gesto 2';
            app.figFpz_3 = uiaxes(app.Gesto2Tab);
            title(app.figFpz_3, 'Gesto 2')
            xlabel(app.figFpz_3, 't (s)')
            ylabel(app.figFpz_3, 'V')
            app.Canal1Label = uilabel(app.Gesto2Tab);
            app.Canal1Label.Text = 'Canal 1';
            app.RegresarButton = uibutton(app.UIFigure, 'push');
            app.RegresarButton.ButtonPushedFcn = createCallbackFcn(app, @RegresarButtonPushed2, true);
            app.RegresarButton.Text = '< Regresar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RecoleccionDeDatosEMG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end